classdef ScanInit_EA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ScanTab                   matlab.ui.container.Tab
        ParameterPanel            matlab.ui.container.Panel
        InstrLabel                matlab.ui.control.Label
        FromLabel                 matlab.ui.control.Label
        XCheckBox                 matlab.ui.control.CheckBox
        xFrom                     matlab.ui.control.NumericEditField
        StepLabel                 matlab.ui.control.Label
        ToLabel                   matlab.ui.control.Label
        DelayLabel                matlab.ui.control.Label
        RepeatLabel               matlab.ui.control.Label
        xStep                     matlab.ui.control.NumericEditField
        xTo                       matlab.ui.control.NumericEditField
        xDelay                    matlab.ui.control.NumericEditField
        xRepeat                   matlab.ui.control.NumericEditField
        YCheckBox                 matlab.ui.control.CheckBox
        yFrom                     matlab.ui.control.NumericEditField
        yStep                     matlab.ui.control.NumericEditField
        yTo                       matlab.ui.control.NumericEditField
        yDelay                    matlab.ui.control.NumericEditField
        yRepeat                   matlab.ui.control.NumericEditField
        ZCheckBox                 matlab.ui.control.CheckBox
        zFrom                     matlab.ui.control.NumericEditField
        zStep                     matlab.ui.control.NumericEditField
        zTo                       matlab.ui.control.NumericEditField
        zDelay                    matlab.ui.control.NumericEditField
        zRepeat                   matlab.ui.control.NumericEditField
        XInstr                    matlab.ui.control.DropDown
        XCh                       matlab.ui.control.DropDown
        YInstr                    matlab.ui.control.DropDown
        YCh                       matlab.ui.control.DropDown
        ZInstr                    matlab.ui.control.DropDown
        ZCh                       matlab.ui.control.DropDown
        ChLabel                   matlab.ui.control.Label
        ReadPanel                 matlab.ui.container.Panel
        ChangeFilePathButton      matlab.ui.control.Button
        FilePathSet               matlab.ui.control.EditField
        FilePathLabel             matlab.ui.control.Label
        ReadLabel                 matlab.ui.control.Label
        InstrumentLabel           matlab.ui.control.Label
        ChannelLabel              matlab.ui.control.Label
        ReadInstrument            matlab.ui.control.DropDown
        ReadChannel               matlab.ui.control.DropDown
        ReadButtonGroup           matlab.ui.container.ButtonGroup
        AddButton                 matlab.ui.control.ToggleButton
        DeleteButton              matlab.ui.control.ToggleButton
        Button                    matlab.ui.container.Panel
        ScanButton                matlab.ui.control.Button
        DisableLabel              matlab.ui.control.Label
        SettingButton             matlab.ui.container.Panel
        InstrumentsSettingButton  matlab.ui.control.Button
        InitialTab                matlab.ui.container.Tab
        InstrDropDownLabel        matlab.ui.control.Label
        InstrDropDown             matlab.ui.control.DropDown
        Lockin1Panel              matlab.ui.container.Panel
        Amp1EditField             matlab.ui.control.NumericEditField
        Amp1CheckBox              matlab.ui.control.CheckBox
        Freq1EditField            matlab.ui.control.NumericEditField
        Freq1CheckBox             matlab.ui.control.CheckBox
        X1EditField               matlab.ui.control.NumericEditField
        X1CheckBox                matlab.ui.control.CheckBox
        Sens1CheckBox             matlab.ui.control.CheckBox
        Sens1DropDown             matlab.ui.control.DropDown
        All1CheckBox              matlab.ui.control.CheckBox
        A1EditField               matlab.ui.control.NumericEditField
        A1CheckBox                matlab.ui.control.CheckBox
        A2EditField               matlab.ui.control.NumericEditField
        A2CheckBox                matlab.ui.control.CheckBox
        A3EditField               matlab.ui.control.NumericEditField
        A3CheckBox                matlab.ui.control.CheckBox
        A4EditField               matlab.ui.control.NumericEditField
        A4CheckBox                matlab.ui.control.CheckBox
        Lockin2Panel              matlab.ui.container.Panel
        B1EditField               matlab.ui.control.NumericEditField
        B1CheckBox                matlab.ui.control.CheckBox
        B2EditField               matlab.ui.control.NumericEditField
        B2CheckBox                matlab.ui.control.CheckBox
        B3EditField               matlab.ui.control.NumericEditField
        B3CheckBox                matlab.ui.control.CheckBox
        B4EditField               matlab.ui.control.NumericEditField
        B4CheckBox                matlab.ui.control.CheckBox
        Amp2EditField             matlab.ui.control.NumericEditField
        Amp2CheckBox              matlab.ui.control.CheckBox
        Freq2EditField            matlab.ui.control.NumericEditField
        Freq2CheckBox             matlab.ui.control.CheckBox
        X2EditField               matlab.ui.control.NumericEditField
        X2CheckBox                matlab.ui.control.CheckBox
        Sens2CheckBox             matlab.ui.control.CheckBox
        Sens2DropDown             matlab.ui.control.DropDown
        All2CheckBox              matlab.ui.control.CheckBox
        ItekPanel                 matlab.ui.container.Panel
        U1EditField               matlab.ui.control.NumericEditField
        U1CheckBox                matlab.ui.control.CheckBox
        All3CheckBox              matlab.ui.control.CheckBox
        U2EditField               matlab.ui.control.NumericEditField
        U2CheckBox                matlab.ui.control.CheckBox
        U3EditField               matlab.ui.control.NumericEditField
        U3CheckBox                matlab.ui.control.CheckBox
        U4EditField               matlab.ui.control.NumericEditField
        U4CheckBox                matlab.ui.control.CheckBox
        U5EditField               matlab.ui.control.NumericEditField
        U5CheckBox                matlab.ui.control.CheckBox
        U6EditField               matlab.ui.control.NumericEditField
        U6CheckBox                matlab.ui.control.CheckBox
        U7EditField               matlab.ui.control.NumericEditField
        U7CheckBox                matlab.ui.control.CheckBox
        U8EditField               matlab.ui.control.NumericEditField
        U8CheckBox                matlab.ui.control.CheckBox
        U9EditField               matlab.ui.control.NumericEditField
        U9CheckBox                matlab.ui.control.CheckBox
        U10EditField              matlab.ui.control.NumericEditField
        U10CheckBox               matlab.ui.control.CheckBox
        U11EditField              matlab.ui.control.NumericEditField
        U11CheckBox               matlab.ui.control.CheckBox
        U12EditField              matlab.ui.control.NumericEditField
        U12CheckBox               matlab.ui.control.CheckBox
        U13EditField              matlab.ui.control.NumericEditField
        U13CheckBox               matlab.ui.control.CheckBox
        U14EditField              matlab.ui.control.NumericEditField
        U14CheckBox               matlab.ui.control.CheckBox
        U15EditField              matlab.ui.control.NumericEditField
        U15CheckBox               matlab.ui.control.CheckBox
        U16EditField              matlab.ui.control.NumericEditField
        U16CheckBox               matlab.ui.control.CheckBox
        Ips3Panel                 matlab.ui.container.Panel
        XtEditField               matlab.ui.control.NumericEditField
        XtCheckBox                matlab.ui.control.CheckBox
        ZtEditField               matlab.ui.control.NumericEditField
        ZtCheckBox                matlab.ui.control.CheckBox
        XfEditField               matlab.ui.control.NumericEditField
        XfCheckBox                matlab.ui.control.CheckBox
        HeatCheckBox              matlab.ui.control.CheckBox
        HoldCheckBox              matlab.ui.control.CheckBox
        ToZeroCheckBox            matlab.ui.control.CheckBox
        All4CheckBox              matlab.ui.control.CheckBox
        ToZeroButton              matlab.ui.control.StateButton
        HoldButton                matlab.ui.control.StateButton
        HeatButton                matlab.ui.control.StateButton
        ZfPanel                   matlab.ui.container.Panel
        ZinEditField              matlab.ui.control.NumericEditField
        ZinLabel                  matlab.ui.control.Label
        ZexLabel                  matlab.ui.control.Label
        ZexEditField              matlab.ui.control.NumericEditField
        ZfCheckBox                matlab.ui.control.CheckBox
        A33522aPanel              matlab.ui.container.Panel
        Channel2Panel             matlab.ui.container.Panel
        Ch2CheckBox               matlab.ui.control.CheckBox
        Ch2PrdEditField           matlab.ui.control.NumericEditField
        Ch2PrdCheckBox            matlab.ui.control.CheckBox
        Ch2AmpEditField           matlab.ui.control.NumericEditField
        Ch2AmpCheckBox            matlab.ui.control.CheckBox
        Ch2FreqEditField          matlab.ui.control.NumericEditField
        Ch2FreqCheckBox           matlab.ui.control.CheckBox
        Channel1Panel             matlab.ui.container.Panel
        Ch1PrdEditField           matlab.ui.control.NumericEditField
        Ch1PrdCheckBox            matlab.ui.control.CheckBox
        Ch1AmpEditField           matlab.ui.control.NumericEditField
        Ch1AmpCheckBox            matlab.ui.control.CheckBox
        Ch1FreqEditField          matlab.ui.control.NumericEditField
        Ch1FreqCheckBox           matlab.ui.control.CheckBox
        Ch1CheckBox               matlab.ui.control.CheckBox
        initPanel                 matlab.ui.container.Panel
        ReadButton                matlab.ui.control.Button
        InitButton                matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
    end

    
    properties (Access = private)        
        isInit = 0;        
        %Index State Parameter
        dim = 0;
        ScanList = {};
        InitList = {};        
        %Scanning Index
        channel = [];
        instr = [];
        from = [];
        step = [];
        to = [];
        delay = [];
        repeat = [];
        resetStep = [];
        resetDelay = [];
        label = {};
        setters_scan = {};
        filePath = [];
        originalfilePath = [];
        defaultMapPath = [];
        readParam = {};
        channelRead = [];
        labelsRead = {};
        readers_scan = {};
        totaltime = 0;
        totalnumber = 0;
        hwait = {};
        curnumber = 0;
        sensI = {'2fA','5fA','10fA','20fA','50fA','100fA','200fA','500fA','1pA','2pA','5pA','10pA','20pA','50pA','100pA','200pA','500pA','1nA','2nA','5nA','10nA','20nA','50nA','100nA','200nA','500nA','1uA'};
        sensV = {'2nV','5nV','10nV','20nV','50nV','100nV','200nV','500nV','1uV','2uV','5uV','10uV','20uV','50uV','100uV','200uV','500uV','1mV','2mV','5mV','10mV','20mV','50mV','100mV','200mV','500mV','1V'};
        % Setting instruments dialog
        SettingDialog
    end
    
    properties (Access = public)
        instrlist
        lockin1
        lockin2
        itek
        ips
        dmm
        dmm2
        a33522a
    end
    
    
    methods (Access = private)        
        function init_instr(app,reader,setter,value,step,delay,label)
            current_value = reader();
            abs_step = abs(step);
            hlabel = eval(label);
            if current_value>value
                while current_value-value>abs_step
                    if ~app.isInit
                        return;
                    end
                    current_value=current_value - abs_step;
                    setter(current_value);
                    hlabel.Value = current_value;
                    pause(delay);
                end
            else
                while value-current_value>abs_step
                    if ~app.isInit
                        return;
                    end
                    current_value = current_value+abs_step;
                    setter(current_value);
                    hlabel.Value = current_value;
                    pause(delay);
                end
            end
            setter(value);
            hlabel.Value = value;
        end     
    
        function write_parameter(app)
            import tool.*
            % write 'instrument_param.txt'
            instrfile = fullfile(app.filePath,'instrument_param.txt');
            fd = fopen(instrfile,'w');
            if fd == -1
                error('writeParameter:Error','Can not open %s\r\n',instrfile);
            else
                for j = 1:length(app.InstrDropDown.Items)
                    switch app.InstrDropDown.Items{j}
                        case 'lockin1'
                            for i = 1:4
                                fprintf(fd,'A%g\t%g\r\n',i,app.lockin1.read_aux(i));
                            end
                            fprintf(fd,'AMP1\t%g\r\n',app.lockin1.read_amp(0));
                            fprintf(fd,'FREQ1\t%g\r\n',app.lockin1.read_freq(0));
                            
                        case 'lockin2'
                            for i = 1:4
                                fprintf(fd,'B%g\t%g\r\n',i,app.lockin2.read_aux(i));
                            end
                            fprintf(fd,'AMP2\t%g\r\n',app.lockin2.read_amp(0));
                            fprintf(fd,'FREQ2\t%g\r\n',app.lockin2.read_freq(0));
                        case 'lockin3'
                            for i = 1:4
                                fprintf(fd,'C%g\t%g\r\n',i,app.lockin3.read_aux(i));
                            end
                            fprintf(fd,'AMP3\t%g\r\n',app.lockin3.read_amp(0));
                            fprintf(fd,'FREQ3\t%g',app.lockin3.read_freq(0));
                    end
                end
                fclose(fd);
            end            
            %write 'param_prev.txt'
            param_prevfile = fullfile(app.filePath,'param_prev.txt');
            fd = fopen(param_prevfile,'w');
            if fd == -1
                error('writeParameter:Error','Can not open %s\r\n',param_prevfile);
            else
                title = sprintf('\t');
                content = '';
                for i = 1:app.dim
                    title = [title,sprintf('@Sweepgate{%s}',app.label{i})];
                    content = [content,sprintf('@Sweepgate{%s}(%g:%g:%g:%g:%g)',app.label{i},app.from(i),app.step(i),app.to(i),app.delay(i),app.repeat(i))];
                    if i < app.dim
                        title = [title,'-'];
                        content = [content,'  '];
                    else
                        title = [title,':'];
                        content = [content,sprintf('\r\n')];
                    end
                end
                index = zeros(1,length(app.labelsRead));
                for i = 1:length(app.labelsRead)
                    tokens = regexp(app.labelsRead{i},'Current(\d+)','tokens');
                    index(i) = str2double(tokens{1}{1});
                    tmp = regexprep(app.labelsRead{i},'Current(\d+)','@Current$1{Current$1}');
                    title = [title,tmp];
                    if i < length(app.labelsRead)
                        title = [title,'-'];
                    end
                end
                index = sort(unique(index));
                for i = 1:length(index)
                    content = [content,sprintf('@Current%d{Current%d}(@Bias%d  @Modulation%d  @Source%d  @Measure%d)',index(i),index(i),index(i),index(i),index(i),index(i))];
                    if i < length(index)
                        content = [content,'  '];
                    else
                        content = [content,sprintf('\r\n')];
                    end
                end
                fprintf(fd,'%s\r\n%s',title,content);                
                for i = 1:length(app.InstrDropDown.Items)
                    switch app.InstrDropDown.Items{i}
                        case 'lockin1'
                            for j = 1:4
                                fprintf(fd,'A%g(%g)',j,app.lockin1.read_aux(j));
                            end
                        case 'lockin2'
                            for j=1:4
                                fprintf(fd,'B%g(%g)',j,app.lockin2.read_aux(j));
                            end
                        case 'lockin3'
                            for j=1:4
                                fprintf(fd,'C%g(%g)',j,app.lockin3.read_aux(j));
                            end
                        case 'itek'
                            for j = 1:8
                                labels = ['I',char(65+floor((j-1)/4)),num2str(j-floor((j-1)/4)*4)];
                                fprintf(fd,'%s(%g)',labels,app.itek.read_volt(j));
                            end
                            fprintf(fd,'\r\n');
                            for j = 9:16
                                labels = ['I',char(65+floor((j-1)/4)),num2str(j-floor((j-1)/4)*4)];
                                fprintf(fd,'%s(%g)',labels,app.itek.read_volt(j));
                            end
                        case 'e8257d'
                            if e8257d.query_output_state
                                fprintf(fd,'Rffreq(%gGHz)Rfpower(%gdBm)',e8257d.query_freq,e8257d.query_power);
                            end
                        case 'agilent81134a'
                            if agilent81134a.query_dut_state
                                fprintf(fd,'PulseFreq(%gMHz)',agilent81134a.query_freq);
                                if agilent81134a.query_output_state(1)==1
                                    fprintf(fd,'Pw1(%gps)Amp1(%gmv)',agilent81134a.query_width(1),agilent81134a.query_amp(1));
                                end
                                if agilent81134a.query_output_state(2)==1
                                    fprintf(fd,'Pw2(%gps)Amp2(%gmv)',agilent81134a.query_width(2),agilent81134a.query_amp(2));
                                end
                            end
                        case 'sm'
                            fprintf(fd,'BG(%g)',sm.query_volt);
                        case 'is'
                            fprintf(fd,'Bias(%g)',is.read_volt);
                    end
                    fprintf(fd,'\r\n');
                end
                fprintf(fd,'\r\n%s',app.filePath);
                fclose(fd);
                % generate map.txt&parameter.txt
                if exist(instrfile,'file')&&exist(param_prevfile,'file')
                    if exist(app.defaultMapPath,'file')
                        copyfile(app.defaultMapPath,app.filePath);
                        process_parameter_txt(param_prevfile,instrfile,app.defaultMapPath);
                    end
                end
            end
        end
        
        function Scanner(app)%iterators readers are cell array
            import tool.*
            n = app.dim;
            sVal = cell(1,n);
            m = length(app.readers_scan);
            rVal = cell(1,m);
            % 显示画图区域
            close all;
            colorlist = {'black','red','blue','cyan','m'};
            app.curnumber = 0;
            f = cell(1,m);
            cplot =  cell(1,m);
            for i = 1:m
                f{i} = figure;
                f{i}.MenuBar = 'none';
                f{i}.ToolBar = 'none';
                f{i}.Position = [800*i-600 400 800 600];
                f{i}.Color = [1 1 1];
                hold on;
                grid on;
                xlabel(app.label{1})
                ylabel(app.labelsRead{i});
                cplot{i} = animatedline;
                cplot{i}.Color = colorlist{mod(i,5)};
            end
            %次数超过一次且时间超过60s，显示进度条
            if app.totalnumber > 10 || app.totaltime > 60
                tips = sprintf('Scanning ...%d figures remaining, please wait %g seconds',app.totalnumber,app.totaltime);%显示等待进度条
                app.hwait = uiprogressdlg(app.UIFigure,'Value',0,'Message',tips,'Title','Scanning','Cancelable','on');
                pause(1);
                app.curnumber = 1;
                ss = ceil(app.totaltime - app.totaltime * (app.curnumber - 1) / app.totalnumber);%剩余时间/s
                hms = fix(mod(ss, [0, 3600, 60])./[3600, 60, 1]);
                if hms(1)
                    app.hwait.Message = sprintf('Scanning %d of %d figures … %d hours %d minutes %d seconds remaining',app.curnumber,app.totalnumber,hms(1),hms(2),hms(3));
                elseif hms(2)
                    app.hwait.Message = sprintf('Scanning %d of %d figures … %d minutes %d seconds remaining',app.curnumber,app.totalnumber,hms(2),hms(3));
                else
                    app.hwait.Message = sprintf('Scanning %d of %d figures … %d seconds remaining',app.curnumber,app.totalnumber,hms(3));
                end
                app.hwait.Value = app.curnumber/app.totalnumber;
            end
            % 开始扫图
            for i = 1:n
                if app.from(i)-app.to(i) > 0
                    app.step(i) = -app.step(i);
                end
                sVal{i} = unique([app.from(i):app.step(i):app.to(i),app.to(i)],'stable');
            end
            fw = FileWriter(app.dim,app.dim+length(app.readParam),app.filePath);
            fw.reset(0);
            app.ploter(app.dim,length(app.readParam),sVal,rVal,fw,cplot,[]);
            delete(fw);
            if ~isempty(app.hwait)
                close(app.hwait);
            end
        end
        
        function collect_data(app)
            import tool.*;
            numbers = [];
            app.dim = 0;
            app.totaltime = 0;
            app.totalnumber = 1;
            %Check Index dimension & get scanning parameters
            if app.ZCheckBox.Value
                if strcmp(app.ZInstr.Value,'-')
                    error('ParameterError:ZChannel','choose an instrument to scan');
                else
                    app.dim = app.dim + 1;
                    app.instr{app.dim} = eval(['app.' app.ZInstr.Value]);
                    app.channel(app.dim) = app.ZCh.Value;
                    app.from(app.dim) = app.zFrom.Value;
                    app.step(app.dim) = app.zStep.Value;
                    app.to(app.dim) = app.zTo.Value;
                    app.delay(app.dim) = app.zDelay.Value;
                    app.repeat(app.dim) = app.zRepeat.Value;
                    app.resetStep(app.dim) = app.instr{app.dim}.step{app.channel(app.dim)};
                    app.resetDelay(app.dim) = app.instr{app.dim}.delay{app.channel(app.dim)};
                    app.label{app.dim} = app.instr{app.dim}.label{app.channel(app.dim)};
                    app.setters_scan{app.dim} = app.instr{app.dim}.operate('set',app.channel(app.dim));
                    numbers(app.dim) = app.repeat(app.dim)*(floor(abs(app.from(app.dim)-app.to(app.dim))/abs(app.step(app.dim)))+1);
                    if isnan(numbers(app.dim))||numbers(app.dim) == 1||~app.delay(app.dim)
                        error('ParameterError:ZChannel','check your parameters');
                    end
                end
            end
            if app.YCheckBox.Value
                if strcmp(app.YInstr.Value,'-')
                    error('ParameterError:YChannel','choose an instrument to scan');
                else
                    app.dim = app.dim + 1;
                    app.instr{app.dim} = eval(['app.' app.YInstr.Value]);
                    app.channel(app.dim) = app.YCh.Value;
                    app.from(app.dim) = app.yFrom.Value;
                    app.step(app.dim) = app.yStep.Value;
                    app.to(app.dim) = app.yTo.Value;
                    app.delay(app.dim) = app.yDelay.Value;
                    app.repeat(app.dim) = app.yRepeat.Value;
                    app.resetStep(app.dim) = app.instr{app.dim}.step{app.channel(app.dim)};
                    app.resetDelay(app.dim) = app.instr{app.dim}.delay{app.channel(app.dim)};
                    app.label{app.dim} = app.instr{app.dim}.label{app.channel(app.dim)};
                    app.setters_scan{app.dim} = app.instr{app.dim}.operate('set',app.channel(app.dim));
                    numbers(app.dim) = app.repeat(app.dim)*(floor(abs(app.from(app.dim)-app.to(app.dim))/abs(app.step(app.dim)))+1);
                    if isnan(numbers(app.dim))||numbers(app.dim) == 1||~app.delay(app.dim)
                        error('ParameterError:YChannel','check your parameters');
                    end
                end
            end
            if app.XCheckBox.Value
                if strcmp(app.XInstr.Value,'-')
                    %                     uialert(app.UIFigure,'choose an instrument to scan','lack of Parameters@zChannel');
                    %                     return;
                    error('ParameterError:XChannel','choose an instrument to scan');
                else
                    app.dim = app.dim + 1;
                    app.instr{app.dim} = eval(['app.' app.XInstr.Value]);
                    app.channel(app.dim) = app.XCh.Value;
                    app.from(app.dim) = app.xFrom.Value;
                    app.step(app.dim) = app.xStep.Value;
                    app.to(app.dim) = app.xTo.Value;
                    app.delay(app.dim) = app.xDelay.Value;
                    app.repeat(app.dim) = app.xRepeat.Value;
                    app.resetStep(app.dim) = app.instr{app.dim}.step{app.channel(app.dim)};
                    app.resetDelay(app.dim) = app.instr{app.dim}.delay{app.channel(app.dim)};
                    app.label{app.dim} = app.instr{app.dim}.label{app.channel(app.dim)};
                    app.setters_scan{app.dim} = app.instr{app.dim}.operate('set',app.channel(app.dim));
                    numbers(app.dim) = app.repeat(app.dim)*(floor(abs(app.from(app.dim)-app.to(app.dim))/abs(app.step(app.dim)))+1);
                    if isnan(numbers(app.dim))||numbers(app.dim) == 1||~app.delay(app.dim)
                        error('ParameterError:XChannel','check your parameters');
                    end
                end
            end
            % if no channel choose, error and return
            if ~app.dim
                error('ParameterError:noChannelSelected','choose at least 1 channel');
            end
            % if select same channel, error and return
            if length(app.label)>length(unique(app.label))
                error('ParameterError:ChannelSelected','check instrument&channel');
            end
            % check read parameter
            if isempty(app.readParam)
                error('ParameterError:ReadChannel','choose an instrument to Read');
            end
            for i = 1:length(app.readParam)
                app.readers_scan{i} = app.readParam(i).operate('read',app.channelRead(i));
                app.labelsRead{i} = app.readParam(i).label{app.channelRead(i)};
            end
            % check file path and creat dir
            app.filePath = app.FilePathSet.Value;
            if isempty(app.filePath)
                error('ParameterError:FilePath','lack of a file path');
            end
            if ~exist(app.filePath,'dir')
                mkdir(app.filePath);
            end
            app.originalfilePath=app.filePath;
            datestring=datestr(now,'mmmm-dd-yyyy_HH-MM-SS');
            app.filePath=fullfile(app.filePath,datestring);
            if ~exist(app.filePath,'dir')
                mkdir(app.filePath);
            end
            defaultPath='E:\MatlabGithub\toolbox\Defaults_para\Defaults_setting';
            app.defaultMapPath=fullfile(defaultPath,'map.txt');
            % calculate totaltime and totalnumber and then initiat
            for i = 1:app.dim
                app.totaltime = (app.delay(i)+app.totaltime)*numbers(i);
                if i < app.dim
                    app.totalnumber = numbers(i+1)*app.totalnumber;
                end
                init_channel(app.instr{app.dim},app.channel(app.dim),app.from(app.dim),app.resetStep(app.dim),app.resetDelay(app.dim));
            end
            % write parameters into file
            app.write_parameter;
            % set UI
            app.XCheckBox.Enable = 0;
            app.XCheckBoxValueChanged;
            app.YCheckBox.Enable = 0;
            app.YCheckBoxValueChanged;
            app.ZCheckBox.Enable = 0;
            app.ZCheckBoxValueChanged;
            app.ReadInstrument.Enable = 0;
            app.ReadInstrumentValueChanged;
            app.FilePathSet.Enable = 0;
            app.ScanButton.Enable = 0;
            app.InstrumentsSettingButton.Enable = 0;
        end
        
        function ploter(app,n,m,sVal,rVal,fw,cplot,cur)
            % n = current dims, m = dimr
            % sVal = sweep values, rVal = read value
            % cplot = cell of figure handles, cur = sweep position
            % varargin = uiprogress handle
            if n > 1
                for i = 1:length(sVal{n})
                    app.setters_scan{n}(sVal{n}(i));
                    for j = 1:app.repeat(n)                        
                        pause(app.delay(n));
                        ploter(app,n-1,m,sVal,rVal,fw,cplot,[cur sVal{n}(i)]);
                        fw.reset(length(cur)+1,2);
                        app.curnumber = app.curnumber+1;
                        if ~isempty(app.hwait)&&app.curnumber <= app.totalnumber
                            if app.hwait.CancelRequested
                                close(app.hwait);
                                error('ScanProess:Cancelled','Cnacelled by user!');
                            end
                            ss = ceil(app.totaltime - app.totaltime * (app.curnumber - 1) / app.totalnumber);
                            hms = fix(mod(ss, [0, 3600, 60])./[3600, 60, 1]);
                            if hms(1)
                                tips = sprintf('Scanning %d of %d figures … %d hours %d minutes %d seconds remaining',app.curnumber,app.totalnumber,hms(1),hms(2),hms(3));
                            elseif hms(2)
                                tips = sprintf('Scanning %d of %d figures … %d minutes %d seconds remaining',app.curnumber,app.totalnumber,hms(2),hms(3));
                            else
                                tips = sprintf('Scanning %d of %d figures … %d seconds remaining',app.curnumber,app.totalnumber,hms(3));
                            end
                            app.hwait.Message = tips;
                            app.hwait.Value = app.curnumber/app.totalnumber;
                        end
                        for k = 1:m
                            clearpoints(cplot{k});
                        end
                    end
                    fw.reset(length(cur)+1,1);
                end
                app.curnumber = app.curnumber-1;
                tool.init_channel(app.instr{n},app.channel(n),sVal{n}(1),app.resetStep(n),app.resetDelay(n));
            else
                for i = 1:length(sVal{n})
                    app.setters_scan{n}(sVal{n}(i));
                    for j = 1:app.repeat(n)
                        for k = 1:m
                            pause(app.delay(n));
                            rVal{k}  = app.readers_scan{k}();
                            addpoints(cplot{k},sVal{n}(i),rVal{k});
                        end
                        fw.write(num2cell([cur sVal{n}(i)]),rVal);
                        drawnow;
                    end
                end
                tool.init_channel(app.instr{n},app.channel(n),sVal{n}(1),app.resetStep(n),app.resetDelay(n));
            end
        end
    end
    
    methods (Access = public)
        function check_instrlist(app)
            app.ScanList = intersect(app.instrlist,{'lockin1','lockin2','dmm','dmm2'});
            if isempty(app.ScanList)
                app.ParameterPanel.Visible = 0;
                app.ReadPanel.Visible = 0;
                app.Button.Visible = 0;
                app.DisableLabel.Visible = 1;
            else
                app.ParameterPanel.Visible = 1;
                app.ReadPanel.Visible = 1;
                app.Button.Visible = 1;
                app.DisableLabel.Visible = 0;
                app.ReadInstrument.Items = ['-';app.ScanList];
            end
            app.InitList = intersect(app.instrlist,{'-','lockin1','lockin2','itek','ips','a33522a'});
            app.InstrDropDown.Items = app.InitList;
            app.XInstr.Items = app.InitList;
            app.YInstr.Items = app.InitList;
            app.ZInstr.Items = app.InitList;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import instrument.*;
            import tool.*;
            import real_instrument.*;
            try
            InstrInFile = readtable('.\Defaults_para\Defaults_setting\instrlist.txt','Format','%s%s%s%s','Delimiter',',');
            InstrInFile = table2cell(InstrInFile);
            for i = 1:size(InstrInFile,1)
                switch InstrInFile{i,2}
                    case {'VISA-GPIB','GPIB'}
                        InstrInFile{i,3} = str2double(InstrInFile{i,3});                        
                end
                InstrInFile{i,4} = str2double(InstrInFile{i,4});
                switch InstrInFile{i,1}
                    case 'lockin1'
                        app.lockin1 = instr_SR830(InstrInFile(i,2:4),'.\Defaults_para\Defaults_setting\instr_para\lockin1.txt');
                    case 'lockin2'
                        app.lockin2 = instr_SR830(InstrInFile(i,2:4),'.\Defaults_para\Defaults_setting\instr_para\lockin1.txt');
                    case 'itek'
                        app.itek = instr_ITEK(InstrInFile(i,2:4),'.\Defaults_para\Defaults_setting\instr_para\itek.txt');
                    case 'dmm'
                        app.dmm = instr_KEITHLEY2015(InstrInFile(i,2:4),'.\Defaults_para\Defaults_setting\instr_para\dmm.txt');
                    case 'dmm2'
                        app.dmm2 = instr_KEITHLEY2015(InstrInFile(i,2:4),'.\Defaults_para\Defaults_setting\instr_para\dmm2.txt');
                    case 'a33522a'
                        app.a33522a = instr_A33522A(InstrInFile(i,2:4),'.\Defaults_para\Defaults_setting\instr_para\a33522a.txt');
                end
            end
            app.instrlist = ['-';InstrInFile(:,1)];
            catch
                app.instrlist = '-';
            end
            app.check_instrlist;            
        end

        % Value changed function: XCheckBox
        function XCheckBoxValueChanged(app, event)
            value = app.XCheckBox.Value&strcmp(app.XCheckBox.Enable,'on');
            app.XInstr.Enable = value;
            if value
                app.XInstrValueChanged;
            else
                app.XCh.Enable = 0;
            end
            app.xFrom.Enable = value;
            app.xRepeat.Enable = value;
            app.xStep.Enable = value;
            app.xTo.Enable = value;
            app.xDelay.Enable = value;
        end

        % Value changed function: YCheckBox
        function YCheckBoxValueChanged(app, event)
            value = app.YCheckBox.Value&strcmp(app.YCheckBox.Enable,'on');
            app.YInstr.Enable = value;
            if value
                app.YInstrValueChanged;
            else
                app.YCh.Enable = 0;
            end
            app.yFrom.Enable = value;
            app.yStep.Enable = value;
            app.yTo.Enable = value;
            app.yDelay.Enable = value;
            app.yRepeat.Enable = value;
        end

        % Value changed function: ZCheckBox
        function ZCheckBoxValueChanged(app, event)
            value = app.ZCheckBox.Value&strcmp(app.ZCheckBox.Enable,'on');
            app.ZInstr.Enable = value;
            if value
                app.ZInstrValueChanged;
            else
                app.ZCh.Enable = 0;
            end
            app.zFrom.Enable = value;
            app.zStep.Enable = value;
            app.zTo.Enable = value;
            app.zDelay.Enable = value;
            app.zRepeat.Enable = value;
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            try
                %check state &set UI figure off
                collect_data(app);                
                % scan
                app.Scanner;
            catch exception
                uialert(app.UIFigure,exception.message,exception.identifier);
            end
            %UI figure on
            app.XCheckBox.Enable = 1;
            app.XCheckBoxValueChanged;
            app.YCheckBox.Enable = 1;
            app.YCheckBoxValueChanged;
            app.ZCheckBox.Enable = 1;
            app.ZCheckBoxValueChanged;
            app.ReadInstrument.Enable = 1;
            app.ReadInstrumentValueChanged;
            app.FilePathSet.Enable = 1;
            app.ScanButton.Enable = 1;
            app.InstrumentsSettingButton.Enable = 1;
        end

        % Value changed function: InstrDropDown
        function InstrDropDownValueChanged(app, event)
            value = app.InstrDropDown.Value;
            switch value
                case '-'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                case 'lockin1'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','ON');
                case 'lockin2'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','ON');
                case 'itek'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','ON');
                case 'ips'
                    set(app.A33522aPanel,'Visible','OFF');
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','ON');
                case 'a33522a'
                    set(app.ItekPanel,'Visible','OFF');
                    set(app.Lockin2Panel,'Visible','OFF');
                    set(app.Lockin1Panel,'Visible','OFF');
                    set(app.Ips3Panel,'Visible','OFF');
                    set(app.A33522aPanel,'Visible','ON');
                otherwise
                    errordlg('error!');
            end
        end

        % Value changed function: A1CheckBox
        function A1CheckBoxValueChanged(app, event)
            value = app.A1CheckBox.Value&&strcmp(app.A1CheckBox.Enable,'on');
            app.A1EditField.Enable = value;
        end

        % Value changed function: A2CheckBox
        function A2CheckBoxValueChanged(app, event)
            value = app.A2CheckBox.Value&&strcmp(app.A2CheckBox.Enable,'on');
            app.A2EditField.Enable = value;
        end

        % Value changed function: A3CheckBox
        function A3CheckBoxValueChanged(app, event)
            value = app.A3CheckBox.Value&&strcmp(app.A3CheckBox.Enable,'on');
            app.A3EditField.Enable = value;
        end

        % Value changed function: A4CheckBox
        function A4CheckBoxValueChanged(app, event)
            value = app.A4CheckBox.Value&&strcmp(app.A4CheckBox.Enable,'on');
            app.A4EditField.Enable = value;
        end

        % Value changed function: Amp1CheckBox
        function Amp1CheckBoxValueChanged(app, event)
            value = app.Amp1CheckBox.Value&&strcmp(app.Amp1CheckBox.Enable,'on');
            app.Amp1EditField.Enable = value;
        end

        % Value changed function: Freq1CheckBox
        function Freq1CheckBoxValueChanged(app, event)
            value = app.Freq1CheckBox.Value&&strcmp(app.Freq1CheckBox.Enable,'on');
            app.Freq1EditField.Enable = value;
        end

        % Value changed function: X1CheckBox
        function X1CheckBoxValueChanged(app, event)
            value = app.X1CheckBox.Value&&strcmp(app.X1CheckBox.Enable,'on');
            app.X1EditField.Enable = value;
        end

        % Value changed function: Sens1CheckBox
        function Sens1CheckBoxValueChanged(app, event)
            value = app.Sens1CheckBox.Value&&strcmp(app.Sens1CheckBox.Enable,'on');
            app.Sens1EditField.Enable = value;
        end

        % Value changed function: All1CheckBox
        function All1CheckBoxValueChanged(app, event)
            value = app.All1CheckBox.Value&&strcmp(app.All1CheckBox.Enable,'on');
            app.A1CheckBox.Enable = value;
            app.A1CheckBoxValueChanged;
            app.A2CheckBox.Enable = value;
            app.A2CheckBoxValueChanged;
            app.A3CheckBox.Enable = value;
            app.A3CheckBoxValueChanged;
            app.A4CheckBox.Enable = value;
            app.A4CheckBoxValueChanged;
            app.Amp1CheckBox.Enable = value;
            app.Amp1CheckBoxValueChanged;
            app.Freq1CheckBox.Enable = value;
            app.Freq1CheckBoxValueChanged;
            app.X1CheckBox.Enable = value;
            app.X1CheckBoxValueChanged;
            app.Sens1CheckBox.Enable = value;
            app.Sens1CheckBoxValueChanged;
        end

        % Value changed function: B1CheckBox
        function B1CheckBoxValueChanged(app, event)
            value = app.B1CheckBox.Value&&strcmp(app.B1CheckBox.Enable,'on');
            app.B1EditField.Enable = value;
        end

        % Value changed function: B2CheckBox
        function B2CheckBoxValueChanged(app, event)
            value = app.B2CheckBox.Value&&strcmp(app.B2CheckBox.Enable,'on');
            app.B2EditField.Enable = value;
        end

        % Value changed function: B3CheckBox
        function B3CheckBoxValueChanged(app, event)
            value = app.B3CheckBox.Value&&strcmp(app.B3CheckBox.Enable,'on');
            app.B3EditField.Enable = value;
        end

        % Value changed function: B4CheckBox
        function B4CheckBoxValueChanged(app, event)
            value = app.B4CheckBox.Value&&strcmp(app.B4CheckBox.Enable,'on');
            app.B4EditField.Enable = value;
        end

        % Value changed function: Amp2CheckBox
        function Amp2CheckBoxValueChanged(app, event)
            value = app.Amp2CheckBox.Value&&strcmp(app.Amp2CheckBox.Enable,'on');
            app.Amp2EditField.Enable = value;
        end

        % Value changed function: Freq2CheckBox
        function Freq2CheckBoxValueChanged(app, event)
            value = app.Freq2CheckBox.Value&&strcmp(app.Freq2CheckBox.Enable,'on');
            app.Freq2EditField.Enable = value;
        end

        % Value changed function: X2CheckBox
        function X2CheckBoxValueChanged(app, event)
            value = app.X2CheckBox.Value&&strcmp(app.X2CheckBox.Enable,'on');
            app.X2EditField.Enable = value;
        end

        % Value changed function: Sens2CheckBox
        function Sens2CheckBoxValueChanged(app, event)
            value = app.Sens2CheckBox.Value&&strcmp(app.Snes2CheckBox.Enable,'on');
            app.Snes2EditField.Enable = value;
        end

        % Value changed function: All2CheckBox
        function All2CheckBoxValueChanged(app, event)
            value = app.All2CheckBox.Value&&strcmp(app.All2CheckBox.Enable,'on');
            app.B1CheckBox.Enable = value;
            app.B1CheckBoxValueChanged;
            app.B2CheckBox.Enable = value;
            app.B2CheckBoxValueChanged;
            app.B3CheckBox.Enable = value;
            app.B3CheckBoxValueChanged;
            app.B4CheckBox.Enable = value;
            app.B4CheckBoxValueChanged;
            app.Amp2CheckBox.Enable = value;
            app.Amp2CheckBoxValueChanged;
            app.Freq2CheckBox.Enable = value;
            app.Freq2CheckBoxValueChanged;
            app.X2CheckBox.Enable = value;
            app.X2CheckBoxValueChanged;
            app.Sens2CheckBox.Enable = value;
            app.Sens2CheckBoxValueChanged;
        end

        % Value changed function: U1CheckBox
        function U1CheckBoxValueChanged(app, event)
            value = app.U1CheckBox.Value&&strcmp(app.U1CheckBox.Enable,'on');
            app.U1EditField.Enable = value;
        end

        % Value changed function: U2CheckBox
        function U2CheckBoxValueChanged(app, event)
            value = app.U2CheckBox.Value&&strcmp(app.U2CheckBox.Enable,'on');
            app.U2EditField.Enable = value;
        end

        % Value changed function: U3CheckBox
        function U3CheckBoxValueChanged(app, event)
            value = app.U3CheckBox.Value&&strcmp(app.U3CheckBox.Enable,'on');
            app.U3EditField.Enable = value;
        end

        % Value changed function: U4CheckBox
        function U4CheckBoxValueChanged(app, event)
            value = app.U4CheckBox.Value&&strcmp(app.U4CheckBox.Enable,'on');
            app.U4EditField.Enable = value;
        end

        % Value changed function: U5CheckBox
        function U5CheckBoxValueChanged(app, event)
            value = app.U5CheckBox.Value&&strcmp(app.U5CheckBox.Enable,'on');
            app.U5EditField.Enable = value;
        end

        % Value changed function: U6CheckBox
        function U6CheckBoxValueChanged(app, event)
            value = app.U6CheckBox.Value&&strcmp(app.U6CheckBox.Enable,'on');
            app.U6EditField.Enable = value;
        end

        % Value changed function: U7CheckBox
        function U7CheckBoxValueChanged(app, event)
            value = app.U7CheckBox.Value&&strcmp(app.U7CheckBox.Enable,'on');
            app.U7EditField.Enable = value;
        end

        % Value changed function: U8CheckBox
        function U8CheckBoxValueChanged(app, event)
            value = app.U8CheckBox.Value&&strcmp(app.U8CheckBox.Enable,'on');
            app.U8EditField.Enable = value;
        end

        % Value changed function: U9CheckBox
        function U9CheckBoxValueChanged(app, event)
            value = app.U9CheckBox.Value&&strcmp(app.U9CheckBox.Enable,'on');
            app.U9EditField.Enable = value;
        end

        % Value changed function: U10CheckBox
        function U10CheckBoxValueChanged(app, event)
            value = app.U10CheckBox.Value&&strcmp(app.U10CheckBox.Enable,'on');
            app.U10EditField.Enable = value;
        end

        % Value changed function: U11CheckBox
        function U11CheckBoxValueChanged(app, event)
            value = app.U11CheckBox.Value&&strcmp(app.U11CheckBox.Enable,'on');
            app.U11EditField.Enable = value;
        end

        % Value changed function: U12CheckBox
        function U12CheckBoxValueChanged(app, event)
            value = app.U12CheckBox.Value&&strcmp(app.U12CheckBox.Enable,'on');
            app.U12EditField.Enable = value;
        end

        % Value changed function: U13CheckBox
        function U13CheckBoxValueChanged(app, event)
            value = app.U13CheckBox.Value&&strcmp(app.U13CheckBox.Enable,'on');
            app.U13EditField.Enable = value;
        end

        % Value changed function: U14CheckBox
        function U14CheckBoxValueChanged(app, event)
            value = app.U14CheckBox.Value&&strcmp(app.U14CheckBox.Enable,'on');
            app.U14EditField.Enable = value;
        end

        % Value changed function: U15CheckBox
        function U15CheckBoxValueChanged(app, event)
            value = app.U15CheckBox.Value&&strcmp(app.U15CheckBox.Enable,'on');
            app.U15EditField.Enable = value;
        end

        % Value changed function: U16CheckBox
        function U16CheckBoxValueChanged(app, event)
            value = app.U16CheckBox.Value&&strcmp(app.U16CheckBox.Enable,'on');
            app.U16EditField.Enable = value;
        end

        % Value changed function: All3CheckBox
        function All3CheckBoxValueChanged(app, event)
            value = app.All3CheckBox.Value&&strcmp(app.All3CheckBox.Enable,'on');
            for i = 1:16
                set(eval(sprintf('app.U%dEditField',i)),'Enable',value);
                eval(sprintf('app.U%dCheckBoxValueChanged',i));
            end
        end

        % Value changed function: XtCheckBox
        function XtCheckBoxValueChanged(app, event)
            value = app.XtCheckBox.Value&&strcmp(app.XtCheckBox.Enable,'on');
            app.XtEditField.Enable = value;
        end

        % Value changed function: ZtCheckBox
        function ZtCheckBoxValueChanged(app, event)
            value = app.ZtCheckBox.Value&&strcmp(app.ZtCheckBox.Enable,'on');
            app.ZtEditField.Enable = value;
        end

        % Value changed function: XfCheckBox
        function XfCheckBoxValueChanged(app, event)
            value = app.XfCheckBox.Value&&strcmp(app.XfCheckBox.Enable,'on');
            app.XfEditField.Enable = value;
        end

        % Value changed function: ZfCheckBox
        function ZfCheckBoxValueChanged(app, event)
            value = app.ZfCheckBox.Value&&strcmp(app.ZfCheckBox.Enable,'on');
            app.ZexEditField.Enable = value;
            app.ZinEditField.Enable = value;
        end

        % Value changed function: HeatCheckBox
        function HeatCheckBoxValueChanged(app, event)
            value = app.HeatCheckBox.Value&&strcmp(app.HeatCheckBox.Enable,'on');
            app.HeatButton.Enable = value;
        end

        % Value changed function: HoldCheckBox
        function HoldCheckBoxValueChanged(app, event)
            value = app.HoldCheckBox.Value&&strcmp(app.HoldCheckBox.Enable,'on');
            app.HoldButton.Enable = value;
        end

        % Value changed function: ToZeroCheckBox
        function ToZeroCheckBoxValueChanged(app, event)
            value = app.ToZeroCheckBox.Value&&strcmp(app.ToZeroCheckBox.Enable,'on');
            app.ToZeroButton.Enable = value;
        end

        % Value changed function: All4CheckBox
        function All4CheckBoxValueChanged(app, event)
            value = app.All4CheckBox.Value&&strcmp(app.All4CheckBox.Enable,'on');
            app.XtCheckBox.Enable = value;
            app.XtCheckBoxValueChanged;
            app.ZtCheckBox.Enable = value;
            app.ZtCheckBoxValueChanged;
            app.XfCheckBox.Enable = value;
            app.XfCheckBoxValueChanged;
            app.ZfCheckBox.Enable = value;
            app.ZfCheckBoxValueChanged;
            app.HeatCheckBox.Enable = value;
            app.HeatCheckBoxValueChanged;
            app.HoldCheckBox.Enable = value;
            app.HoldCheckBoxValueChanged;
            app.ToZeroCheckBox.Enable = value;
            app.ToZeroCheckBoxValueChanged;
        end

        % Value changed function: HeatButton
        function HeatButtonValueChanged(app, event)
            %turn off ips3 panel
            app.All4CheckBox.Enable = 0;
            app.All4CheckBoxValueChanged;
            set(app.InitButton,'Enable','off');
            set(app.ReadButton,'Enable','off');
            %set parameters
            try
                value = app.HeatButton.Text;
                if strcmp(value,'?')||strcmp(value,'OFF')
                    if app.ips3.set_heat(3,1)
                        app.HeatButton.Text = 'ON';
                    end
                elseif strcmp(value,'ON')
                    if app.ips3.set_heat(3,0)
                        app.HeatButton.Text = 'OFF';
                    end
                end
            catch exception
                app.All4CheckBox.Enable = 1;
                app.All4CheckBoxValueChanged;
                set(app.InitButton,'Enable','on');
                set(app.ReadButton,'Enable','on');
                uialert(app.UIFigure,exception.message,exception.identifier);
            end
            %turn on ips3 panel
            app.All4CheckBox.Enable = 1;
            app.All4CheckBoxValueChanged;
            set(app.InitButton,'Enable','on');
            set(app.ReadButton,'Enable','on');            
        end

        % Value changed function: HoldButton
        function HoldButtonValueChanged(app, event)
            try
                value = app.HoldButton.Text;
                if strcmp(value,'?')||strcmp(value,'OFF')
                    app.ips3.set_action(1,1);
                    app.ips3.set_action(3,1);
                    app.HoldButton.Text = 'ON';
                elseif strcmp(value,'ON')
                    app.ips3.set_action(1,0);
                    app.ips3.set_action(3,0);
                    app.HoldButton.Text = 'OFF';
                end
            catch exception
                uialert(app.UIFigure,exception.message,exception.identifier);
            end
        end

        % Value changed function: ToZeroButton
        function ToZeroButtonValueChanged(app, event)
            %turn off ips3 panel
            app.All4CheckBox.Enable = 0;
            app.All4CheckBoxValueChanged;
            app.InitButton.Enable = 'off';
            app.ReadButton.Enable = 'off';
            try
            app.ips3.to_zero_x();
            app.ips3.to_zero_z();
            catch exception
                %turn on ips3 panel
                app.All4CheckBox.Enable = 1;
                app.All4CheckBoxValueChanged;
                app.InitButton.Enable = 'on';
                app.ReadButton.Enable = 'on';
                uialert(app.UIFigure,exception.message,exception.identifier);
            end            
            %turn on ips3 panel
            app.All4CheckBox.Enable = 1;
            app.All4CheckBoxValueChanged;
            app.InitButton.Enable = 'on';
            app.ReadButton.Enable = 'on';
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            app.InitButton.Enable = 0;
            app.ReadButton.Enable = 0;
            try
                switch app.InstrDropDown.Value
                    case 'lockin1'
                        for i = 1:4
                            if eval(sprintf('app.A%dCheckBox.Value',i))
                                set(eval(sprintf('app.A%dEditField',i)),'Value', app.lockin1.read_aux(i));
                            end
                        end
                        if app.Sens1CheckBox.Value
                            if app.lockin1.read_input_mode()
                                app.Sens1DropDown.Items = app.sensV;
                            else
                                app.Sens1DropDown.Items = app.sensI;
                            end
                            
                            sens = app.lockin1.read_sens();
                            app.Sens1DropDown.Value = app.Sens1DropDown.Items(sens+1);
                        end
                        if app.Amp1CheckBox.Value
                            app.Amp1EditField.Value = app.lockin1.read_amp();
                        end
                        if app.Freq1CheckBox.Value
                            app.Freq1EditField.Value = app.lockin1.read_freq();
                        end
                        if app.X1CheckBox.Value
                            app.X1EditField.Value = app.lockin1.read_current(1);
                        end
                    case 'lockin2'
                        for i = 1:4
                            if eval(sprintf('app.B%dCheckBox.Value',i))
                                set(eval(sprintf('app.B%dEditField',i)),'Value',app.lockin2.read_aux(i));
                            end
                        end
                        if app.Sens2CheckBox.Value
                            if app.lockin2.read_input_mode()
                                app.Sens2DropDown.Items = app.sensV;
                            else
                                app.Sens2DropDown.Items = app.sensI;
                            end
                            
                            sens = app.lockin2.read_sens();
                            app.Sens2DropDown.Value = app.Sens2DropDown.Items(sens+1);
                        end
                        if app.Amp2CheckBox.Value
                            app.Amp2EditField.Value = app.lockin2.read_amp();
                        end
                        if app.Freq2CheckBox.Value
                            app.Freq2EditField.Value = app.lockin2.read_freq();
                        end
                        if app.X2CheckBox.Value
                            app.X2EditField.Value = app.lockin2.read_current(1);
                        end
                    case 'itek'
                        for i = 1:16
                            if eval(sprintf('app.U%dCheckBox.Value',i))
                                set(eval(sprintf('app.U%dEditField',i)),'Value',app.itek.read_volt(i));
                            end
                        end
                    case 'ips3'
                        if app.XtCheckBox.Value
                            app.XtEditField.Value = app.ips3.read_ramp_rate(1);
                        end
                        if app.ZtCheckBox.Value
                            app.ZtEditField.Value = app.ips3.read_ramp_rate(3);
                        end
                        if app.XfCheckBox.Value
                            app.XfEditField.Value = app.ips3.read_field(1);
                        end
                        if app.ZfCheckBox.Value
                            app.ZexEditField.Value = app.ips3.read_field(3);
                            app.ZinEditField.Value = app.ips3.read_persistent_field(3);
                        end
                        if app.HeatCheckBox.Value
                            if app.ips3.read_heatState(3)
                                app.HeatButton.Text = 'ON';
                            else
                                app.HeatButton.Text = 'OFF';
                            end
                        end
                        if app.HoldCheckBox.Value
                            if app.ips3.read_action(1) == 1&&app.ips3.read_action(3) == 1
                                app.HoldButton.Text = 'ON';
                            else
                                app.HoldButton.Text = 'OFF';
                            end
                        end
                    case 'a33522a'
                        for i = 1:2
                            if eval(sprintf('app.Ch%dFreqCheckBox.Value',i))
                                set(eval(sprintf('app.Ch%dFreqEditField',i)),'Value',app.a33522a.read_freq(i));
                            end
                            if eval(sprintf('app.Ch%dAmpCheckBox.Value',i))
                                set(eval(sprintf('app.Ch%dAmpEditField',i)),'Value',app.a33522a.read_amp(i));
                            end
                            if eval(sprintf('app.Ch%dPrdCheckBox.Value',i))
                                set(eval(sprintf('app.Ch%dPrdEditField',i)),'Value',app.a33522a.read_period(i));
                            end
                        end
                end
            catch exception
                uialert(app.UIFigure,exception.message,exception.identifier);
            end
            pause(0.5);
            app.InitButton.Enable = 'on';
            app.ReadButton.Enable = 'on';
        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            app.isInit = 1;
            switch app.InstrDropDown.Value
                case 'lockin 1'
                    % turn off lockin 1 panel
                    app.All1CheckBox.Enable = 0;
                    app.All1CheckBoxValueChanged;
                    app.InitButton.Enable = 'off';
                    app.ReadButton.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    %set parameters
                    %A1 ~ A4
                    for i = 1:4
                        if eval(sprintf('app.A%dCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.A%dEditField.Value',i));
                                edit = sprintf('app.A%dEditField',i);
                                reader = @()(app.lockin1.read_aux(i));
                                setters = @(val)(app.lockin1.set_aux(i,val));
                                init_instr(app,reader,setters,value,app.lockin1.stepAux,app.lockin1.delayAux,edit);
                            end
                        end
                    end
                    %Amp
                    if app.Amp1CheckBox.Value
                        if app.isInit
                            value = app.Amp1EditField.Value;
                            edit = 'app.Amp1EditField';
                            reader = @()(app.lockin1.read_amp);
                            setters = @(val)(app.lockin1.set_amp(val));
                            init_instr(app,reader,setters,value,app.lockin2.stepAux,app.lockin2.delayAux,edit);
                        end
                    end
                    %Freq
                    if app.Freq1CheckBox.Value
                        if app.isInit
                            value = app.Freq1EditField.Value;
                            edit = 'app.Freq1EditField';
                            reader = @()(app.lockin1.read_freq);
                            setters = @(val)(app.lockin1.set_freq(val));
                            init_instr(app,reader,setters,value,app.stepAux,app.delayAux,edit);
                        end
                    end
                    %Sens
                    if app.Sens1CheckBox.Value
                        if app.isInit
                            [~, sens] = ismember(app.Sens1DropDown.Value,app.sensI);
                            if ~sens
                                [~, sens] = ismember(app.Sens1DropDown.Value,app.sensV);
                                if ~sens
                                    error('error');
                                end
                            end
                            app.lockin1.set_sens(sens-1);
                        end
                    end                    
                    %turn on lockin 1 panel
                    app.All1CheckBox.Enable = 1;
                    app.All1CheckBoxValueChanged;
                    app.InitButton.Enable = 'on';
                    app.ReadButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                case 'lockin 2'
                    % turn off lockin 2 panel
                    app.All1CheckBox.Enable = 0;
                    app.All1CheckBoxValueChanged;
                    app.InitButton.Enable = 'off';
                    app.ReadButton.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    %set parameters
                    %B1 ~ B4
                    for i = 1:4
                        if eval(sprintf('app.B%dCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.B%dEditField.Value',i));
                                edit = sprintf('app.B%dEditField',i);
                                reader = @()(app.lockin2.read_aux(i));
                                setter = @(val)(app.lockin2.set_aux(i,val));
                                init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                            end
                        end
                    end
                    %Amp
                    if app.Amp2CheckBox.Value
                        if app.isInit
                            value = app.Amp2EditField.Value;
                            edit = 'app.Amp2EditField';
                            reader = @()(app.lockin2.read_amp);
                            setter = @(val)(app.lockin2.set_amp(val));
                            init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                        end
                    end
                    %Freq
                    if app.Freq2CheckBox.Value
                        if app.isInit
                            value = app.Freq2EditField.Value;
                            edit = 'app.Freq2EditField';
                            reader = @()(app.lockin2.read_freq);
                            setter = @(val)(app.lockin2.set_freq(val));
                            init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                        end
                    end
                    %Sens
                    if app.Sens2CheckBox.Value
                        if app.isInit
                            [~, sens] = ismember(app.Sens2DropDown.Value,app.sensI);
                            if ~sens
                                [~, sens] = ismember(app.Sens2DropDown.Value,app.sensV);
                                if ~sens
                                    error('error');
                                end
                            end
                            app.lockin2.set_sens(sens-1);
                        end
                    end
                    %turn on lockin 2 panel
                    app.All1CheckBox.Enable = 1;
                    app.All1CheckBoxValueChanged;
                    app.InitButton.Enable = 'on';
                    app.ReadButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                case 'itek'
                    %turn off itek panel
                    app.All1CheckBox.Enable = 0;
                    app.All1CheckBoxValueChanged;
                    app.InitButton.Enable = 'off';
                    app.ReadButton.Enable = 'off';
                    app.StopButton.Enable = 'on';                  
                    %set parameters
                    for i = 1:16
                        if eval(sprintf('app.U%dCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.U%dEditField.Value',i));
                                edit = sprintf('app.U%dEditField',i);
                                reader = @()(app.itek.read_volt(i));
                                setter = @(val)(app.itek.set_volt(i,val));
                                init_instr(app,reader,setter,value,app.stepAux,app.delayAux,edit);
                            end
                        end
                    end
                    %turn on itek panel
                    app.All1CheckBox.Enable = 1;
                    app.All1CheckBoxValueChanged;
                    app.InitButton.Enable = 'on';
                    app.ReadButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                case 'ips3'
                    %turn off ips3 panel
                    set(app.XtCheckBox,'Enable','off');
                    set(app.ZtCheckBox,'Enable','off');
                    set(app.XfCheckBox,'Enable','off');
                    set(app.ZfCheckBox,'Enable','off');
                    set(app.HeatCheckBox,'Enable','off');
                    set(app.HoldCheckBox,'Enable','off');
                    set(app.ToZeroCheckBox,'Enable','off');
                    set(app.All4CheckBox,'Enable','off');
                    
                    set(app.XtEditField,'Enable','off');
                    set(app.ZtEditField,'Enable','off');
                    set(app.XfEditField,'Enable','off');
                    set(app.ZexEditField,'Enable','off');
                    set(app.ZinEditField,'Enable','off');
                    set(app.HeatButton,'Enable','off');
                    set(app.HoldButton,'Enable','off');
                    set(app.ToZeroButton,'Enable','off');
                    
                    set(app.InitButton,'Enable','off');
                    set(app.ReadButton,'Enable','off');
                    set(app.StopButton,'Enable','on');
                    
                    %set parameters
                    if app.ips3.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.ips3.open_init();
                    end
                    %                     app.ips3.open();
                    if app.XtCheckBox.Value
                        if app.isInit
                            value = app.XtEditField.Value;
                            edit = 'app.XtEditField';
                            reader = @()(app.ips3.read_field_ramp_rate_x);
                            setters = @(val)(app.ips3.set_field_ramp_rate_x(val));
                            init_instr(app,reader,setters,value,app.resetipsStep,app.resetipsDelay,edit);
                        end
                    end
                    if app.ZtCheckBox.Value
                        if app.isInit
                            value = app.ZtEditField.Value;
                            edit = 'app.ZtEditField';
                            reader = @()(app.ips3.read_field_ramp_rate_z);
                            setters = @(val)(app.ips3.set_field_ramp_rate_z(val));
                            init_instr(app,reader,setters,value,app.resetipsStep,app.resetipsDelay,edit);
                        end
                    end
                    if app.XfCheckBox.Value
                        if app.isInit
                            value = app.XfEditField.Value;
                            edit = 'app.XfEditField';
                            reader = @()(app.ips3.read_field_x);
                            setters = @(val)(app.ips3.set_field_x(val));
                            init_instr(app,reader,setters,value,app.resetipsStep,app.resetipsDelay,edit);
                        end
                    end
                    if app.ZfCheckBox.Value
                        if app.isInit
                            if ~app.ips3.check_heater_state_z()
                                msg = sprintf('CONFIRM: Heater is OFF now, so the persistent field will NOT change');
                                choice = questdlg(msg);
                                if strcmpi(choice,'yes')
                                    value = app.ZexEditField.Value;
                                    edit = 'app.ZexEditField';
                                    reader = @()(app.ips3.read_field_z);
                                    setters = @(val)(app.ips3.set_field_z(val));
                                    init_instr(app,reader,setters,value,app.resetipsStep,app.resetipsDelay,edit);
                                end
                            else
                                msg = sprintf('CONFIRM: Heater is ON now, so the persistent field WILL change');
                                choice = questdlg(msg);
                                if strcmpi(choice,'yes')
                                    value = app.ZexEditField.Value;
                                    edit = 'app.ZexEditField';
                                    reader = @()(app.ips3.read_field_z);
                                    setters = @(val)(app.ips3.set_field_z(val));
                                    init_instr(app,reader,setters,value,app.resetipsStep,app.resetipsDelay,edit);
                                end
                            end
                        end
                    end
                    %                     app.ips3.close();
                    if ~flag
                        app.ips3.close_init();
                    end
                    
                    %turn on ips3 panel
                    set(app.XtCheckBox,'Enable','on');
                    set(app.ZtCheckBox,'Enable','on');
                    set(app.XfCheckBox,'Enable','on');
                    set(app.ZfCheckBox,'Enable','on');
                    set(app.HeatCheckBox,'Enable','on');
                    set(app.HoldCheckBox,'Enable','on');
                    set(app.ToZeroCheckBox,'Enable','on');
                    set(app.All4CheckBox,'Enable','on');
                    
                    set(app.XtEditField,'Enable',app.XtCheckBox.Value);
                    set(app.ZtEditField,'Enable',app.ZtCheckBox.Value);
                    set(app.XfEditField,'Enable',app.XfCheckBox.Value);
                    set(app.ZexEditField,'Enable',app.ZfCheckBox.Value);
                    set(app.ZinEditField,'Enable',app.ZfCheckBox.Value);
                    set(app.HeatButton,'Enable',app.HeatCheckBox.Value);
                    set(app.HoldButton,'Enable',app.HoldCheckBox.Value);
                    set(app.ToZeroButton,'Enable',app.ToZeroCheckBox.Value);
                    
                    set(app.InitButton,'Enable','on');
                    set(app.ReadButton,'Enable','on');
                    set(app.StopButton,'Enable','off');
                    
                case 'a33522a'
                    % turn off a33522a panel
                    for i = 1:2
                        set(eval(sprintf('app.Ch%dFreqCheckBox',i)),'Enable','off');
                        set(eval(sprintf('app.Ch%dAmpCheckBox',i)),'Enable','off');
                        set(eval(sprintf('app.Ch%dPrdCheckBox',i)),'Enable','off');
                        
                        set(eval(sprintf('app.Ch%dFreqEditField',i)),'Enable','off');
                        set(eval(sprintf('app.Ch%dAmpEditField',i)),'Enable','off');
                        set(eval(sprintf('app.Ch%dPrdEditField',i)),'Enable','off');
                    end
                    set(app.All5CheckBox,'Enable','off');
                    
                    set(app.InitButton,'Enable','off');
                    set(app.ReadButton,'Enable','off');
                    set(app.StopButton,'Enable','on');
                    
                    
                    %set parameters
                    if app.a33522a.isopened
                        flag = 1;
                    else
                        flag = 0;
                        app.a33522a.open();
                    end
                    
                    for i = 1:2
                        if eval(sprintf('app.Ch%dFreqCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.Ch%dFreqEditField.Value',i));
                                edit = sprintf('app.Ch%dFreqEditField',i);
                                reader = @()(app.a33522a.query_freq(i));
                                setters = @(val)(app.a33522a.set_freq(i,val));
                                init_instr(app,reader,setters,value,app.stepFreq,app.delayFreq,edit);
                            end
                        end
                        if eval(sprintf('app.Ch%dAmpCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.Ch%dAmpEditField.Value',i));
                                edit = sprintf('app.Ch%dAmpEditField',i);
                                reader = @()(app.a33522a.query_amp(i));
                                setters = @(val)(app.a33522a.set_amp(i,val));
                                init_instr(app,reader,setters,value,app.stepAmp,app.delayAmp,edit);
                            end
                        end
                        if eval(sprintf('app.Ch%dPrdCheckBox.Value',i))
                            if app.isInit
                                value = eval(sprintf('app.Ch%dPrdEditField.Value',i));
                                edit = sprintf('app.Ch%dPrdEditField',i);
                                reader = @()(app.a33522a.query_period(i));
                                setters = @(val)(app.a33522a.set_period(i,val));
                                init_instr(app,reader,setters,value,1/app.stepFreq,app.delayFreq,edit);
                            end
                        end
                    end
                    
                    if ~flag
                        app.a33522a.close();
                    end
                    
                    
                    %turn on lockin 2 panel
                    for i = 1:2
                        set(eval(sprintf('app.Ch%dFreqCheckBox',i)),'Enable','on');
                        set(eval(sprintf('app.Ch%dAmpCheckBox',i)),'Enable','on');
                        set(eval(sprintf('app.Ch%dPrdCheckBox',i)),'Enable','on');
                        
                        set(eval(sprintf('app.Ch%dFreqEditField',i)),'Enable',eval(sprintf('app.Ch%dFreqCheckBox.Value',i)));
                        set(eval(sprintf('app.Ch%dAmpEditField',i)),'Enable',eval(sprintf('app.Ch%dAmpCheckBox.Value',i)));
                        set(eval(sprintf('app.Ch%dPrdEditField',i)),'Enable',eval(sprintf('app.Ch%dPrdCheckBox.Value',i)));
                    end
                    set(app.All5CheckBox,'Enable','on');
                    
                    set(app.StopButton,'Enable','off');
                    set(app.ReadButton,'Enable','on');
                    set(app.InitButton,'Enable','on');
                    
                    
            end
            app.isInit = 0;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isInit = 0;
        end

        % Button pushed function: ChangeFilePathButton
        function ChangeFilePathButtonPushed(app, event)
            app.ChangeFilePathButton.Enable = 0;
            try
                dname = uigetdir(app.FilePathSet.Value);
            catch
                dname = uigetdir('E:\People\HRZ\data\test');
            end
            if dname
                app.FilePathSet.Value = dname;
            end
            
            app.ChangeFilePathButton.Enable = 1;
        end

        % Callback function
        function zChannelPushed(app, event)
            app.instrlist = setdiff(app.InstrDropDown.Items,{'-'});
            tips = 'Select instrment:';
            title = 'Instruments selection';
            [Instr_Index,Instr_tf]= listdlg('ListString', app.instrlist,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
            if Instr_tf
                switch app.instrlist{Instr_Index}
                    case 'lockin 1'
                        ChannelList = {'A1','A2','A3','A4','Freq','Amp'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.zChannel.Text = '1';
                                case 2
                                    app.zChannel.Text = '2';
                                case 3
                                    app.zChannel.Text = '3';
                                case 4
                                    app.zChannel.Text = '4';
                                case 5
                                    app.zChannel.Text = '13';
                                case 6
                                    app.zChannel.Text = '14';
                            end
                        end
                    case 'lockin 2'
                        ChannelList = {'B1','B2','B3','B4','Freq','Amp'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.zChannel.Text = '5';
                                case 2
                                    app.zChannel.Text = '6';
                                case 3
                                    app.zChannel.Text = '7';
                                case 4
                                    app.zChannel.Text = '8';
                                case 5
                                    app.zChannel.Text = '15';
                                case 6
                                    app.zChannel.Text = '16';
                            end
                        end
                    case 'itek'
                        ChannelList = {'U1','U2','U3','U4','U5','U6','U7','U8','U9','U10','U11','U12','U13','U14','U15','U16'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            if Ch_Index > 9
                                app.zChannel.Text = sprintf('2%d',Ch_Index);
                            else
                                app.zChannel.Text = sprintf('20%d',Ch_Index);
                            end
                        end
                    case 'ips3'
                        ChannelList = {'X_Field','Z_Field'};
                        tips ='Select Channel:';
                        title = 'Channel Selection';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100],'SelectionMode','single');
                        if Ch_tf
                            switch Ch_Index
                                case 1
                                    app.zChannel.Text = '121';
                                case 2
                                    app.zChannel.Text = '122';
                            end
                        end
                    case 'dmm'
                        app.zChannel.Text = '13';
                    case  'a33522a'
                        ChannelList = {'SquareFreq','SquareAmp','SquarePeriod'};
                        tips ='Select Channel@a33522a:';
                        title = 'Channel@a33522a';
                        [Ch_Index,Ch_tf]= listdlg('ListString', ChannelList,'PromptString',tips,'Name',title,'ListSize',[300,100]);
                        if Ch_tf
                            for j = 1:length(Ch_Index)
                                switch Ch_Index(j)
                                    case 1
                                        app.zChannel.Text = '27';
                                    case 2
                                        app.zChannel.Text = '28';
                                    case 3
                                        app.zChannel.Text = '29';
                                end
                            end
                        end
                end
            end
        end

        % Callback function
        function All5CheckBoxValueChanged(app, event)
            value = app.All5CheckBox.Value;
            set(app.Ch1CheckBox,'Value',value);
            set(app.Ch2CheckBox,'Value',value);
            Ch1CheckBoxValueChanged(app, event);
            Ch2CheckBoxValueChanged(app, event);
        end

        % Value changed function: Ch1CheckBox
        function Ch1CheckBoxValueChanged(app, event)
            value = app.Ch1CheckBox.Value;
            set(app.Ch1FreqCheckBox,'Value',value);
            set(app.Ch1AmpCheckBox,'Value',value);
            set(app.Ch1PrdCheckBox,'Value',value);
            
            set(app.Ch1FreqEditField,'Enable',value);
            set(app.Ch1AmpEditField,'Enable',value);
            set(app.Ch1PrdEditField,'Enable',value);
        end

        % Value changed function: Ch2CheckBox
        function Ch2CheckBoxValueChanged(app, event)
            value = app.Ch2CheckBox.Value;
            set(app.Ch2FreqCheckBox,'Value',value);
            set(app.Ch2AmpCheckBox,'Value',value);
            set(app.Ch2PrdCheckBox,'Value',value);
            
            set(app.Ch2FreqEditField,'Enable',value);
            set(app.Ch2AmpEditField,'Enable',value);
            set(app.Ch2PrdEditField,'Enable',value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.SettingDialog);
            delete(app);
        end

        % Value changed function: XInstr
        function XInstrValueChanged(app, event)
            value = app.XInstr.Value;
            if strcmp(value,'-')
                app.XCh.Enable = 0;
                app.XCh.Items = {'-'};
                app.XCh.ItemsData = [];
            else
                app.XCh.Enable = 1;
                [~,idx] = find(cellfun(@(x) strcmp(x,'set')||strcmp(x,'both'),eval(['app.' value '.operate_type'])));
                app.XCh.Items = eval(['app.' value '.ch(idx)']);
                app.XCh.ItemsData = idx;
            end
        end

        % Value changed function: YInstr
        function YInstrValueChanged(app, event)
            value = app.YInstr.Value;
            if strcmp(value,'-')
                app.YCh.Enable = 0;
                app.YCh.Items = {'-'};
                app.YCh.ItemsData = [];
            else
                app.YCh.Enable = 1;
                [~,idx] = find(cellfun(@(x) strcmp(x,'set')||strcmp(x,'both'),eval(['app.' value '.operate_type'])));
                app.YCh.Items = eval(['app.' value '.ch(idx)']);
                app.YCh.ItemsData = idx;
            end
        end

        % Value changed function: ZInstr
        function ZInstrValueChanged(app, event)
            value = app.ZInstr.Value;
            if strcmp(value,'-')
                app.ZCh.Enable = 0;
                app.ZCh.Items = {'-'};
                app.ZCh.ItemsData = [];
            else
                app.ZCh.Enable = 1;
                [~,idx] = find(cellfun(@(x) strcmp(x,'set')||strcmp(x,'both'),eval(['app.' value '.operate_type'])));
                app.ZCh.Items = eval(['app.' value '.ch(idx)']);
                app.ZCh.ItemsData = idx;
            end
        end

        % Value changed function: ReadInstrument
        function ReadInstrumentValueChanged(app, event)
            value = app.ReadInstrument.Value;
            if strcmp(app.ReadInstrument.Enable,'on')
                if strcmp(value,'-')
                    app.ReadChannel.Enable = 0;
                    app.ReadChannel.Items = {'-'};
                    app.ReadChannel.ItemsData = [];
                    app.AddButton.Enable = 0;
                    app.DeleteButton.Enable = 0;
                else
                    app.ReadChannel.Enable = 1;
                    [~,idx] = find(cellfun(@(x) strcmp(x,'read')||strcmp(x,'both'),eval(['app.' value '.operate_type'])));
                    app.ReadChannel.Items = eval(['app.' value '.ch(idx)']);
                    app.ReadChannel.ItemsData = idx;
                    app.AddButton.Enable = 1;
                    app.DeleteButton.Enable = 1;
                    app.ReadChannelValueChanged;
                end
            else
                app.ReadChannel.Enable = 0;
                app.AddButton.Enable = 0;
                app.DeleteButton.Enable = 0;
            end
        end

        % Selection changed function: ReadButtonGroup
        function ReadButtonGroupSelectionChanged(app, event)
            selectedButton = app.ReadButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Add'                    
                    app.readParam = [app.readParam, eval(['app.' app.ReadInstrument.Value])];
                    app.channelRead = [app.channelRead,app.ReadChannel.Value];
                    app.ReadButtonGroup.Title = sprintf('Ch Selected:%d',length(app.readParam));
                case 'Delete'
                    [~,idx1] = find(arrayfun(@(x) x==eval(['app.' app.ReadInstrument.Value]),app.readParam));
                    [~,idx2] = find(arrayfun(@(x) x == app.ReadChannel.Value,app.channelRead));
                    idx = intersect(idx1,idx2,'stable');
                    app.readParam(idx) = [];
                    app.channelRead(idx) = [];
                    app.ReadButtonGroup.Title = sprintf('Ch Selected:%d',length(app.readParam));
            end
        end

        % Value changed function: ReadChannel
        function ReadChannelValueChanged(app, event)
            [~,idx1] = find(arrayfun(@(x) x == eval(['app.' app.ReadInstrument.Value]),app.readParam));
            [~,idx2] = find(arrayfun(@(x) x == app.ReadChannel.Value,app.channelRead));
            idx = intersect(idx1,idx2,'stable');
            if isempty(idx)
                app.AddButton.Value = 0;
            else
                app.AddButton.Value = 1;
            end
        end

        % Button pushed function: InstrumentsSettingButton
        function InstrumentsSettingButtonPushed(app, event)
            app.InstrumentsSettingButton.Enable = 0;
            app.instrlist = {'-'};
            app.check_instrlist;
            app.SettingDialog = instrument_setting(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.ParameterPanel = uipanel(app.ScanTab);
            app.InstrLabel = uilabel(app.ParameterPanel);
            app.InstrLabel.Text = 'Instr';
            app.FromLabel = uilabel(app.ParameterPanel);
            app.FromLabel.Text = 'From';
            app.XCheckBox = uicheckbox(app.ParameterPanel);
            app.XCheckBox.ValueChangedFcn = createCallbackFcn(app, @XCheckBoxValueChanged, true);
            app.XCheckBox.Text = 'X';
            app.XCheckBox.Value = true;
            app.xFrom = uieditfield(app.ParameterPanel, 'numeric');
            app.xFrom.ValueDisplayFormat = '%11.6g';
            app.StepLabel = uilabel(app.ParameterPanel);
            app.StepLabel.Text = 'Step';
            app.ToLabel = uilabel(app.ParameterPanel);
            app.ToLabel.Text = 'To';
            app.DelayLabel = uilabel(app.ParameterPanel);
            app.DelayLabel.Text = 'Delay';
            app.RepeatLabel = uilabel(app.ParameterPanel);
            app.RepeatLabel.Text = 'Repeat';
            app.xStep = uieditfield(app.ParameterPanel, 'numeric');
            app.xStep.Limits = [0 Inf];
            app.xStep.ValueDisplayFormat = '%11.6g';
            app.xTo = uieditfield(app.ParameterPanel, 'numeric');
            app.xTo.ValueDisplayFormat = '%11.6g';
            app.xDelay = uieditfield(app.ParameterPanel, 'numeric');
            app.xDelay.Limits = [0 Inf];
            app.xDelay.ValueDisplayFormat = '%11.6g';
            app.xRepeat = uieditfield(app.ParameterPanel, 'numeric');
            app.xRepeat.Limits = [1 Inf];
            app.xRepeat.ValueDisplayFormat = '%.0f';
            app.xRepeat.Value = 1;
            app.YCheckBox = uicheckbox(app.ParameterPanel);
            app.YCheckBox.ValueChangedFcn = createCallbackFcn(app, @YCheckBoxValueChanged, true);
            app.YCheckBox.Text = 'Y';
            app.yFrom = uieditfield(app.ParameterPanel, 'numeric');
            app.yFrom.ValueDisplayFormat = '%11.6g';
            app.yFrom.Enable = 'off';
            app.yStep = uieditfield(app.ParameterPanel, 'numeric');
            app.yStep.Limits = [0 Inf];
            app.yStep.ValueDisplayFormat = '%11.6g';
            app.yStep.Enable = 'off';
            app.yTo = uieditfield(app.ParameterPanel, 'numeric');
            app.yTo.ValueDisplayFormat = '%11.6g';
            app.yTo.Enable = 'off';
            app.yDelay = uieditfield(app.ParameterPanel, 'numeric');
            app.yDelay.Limits = [0 Inf];
            app.yDelay.ValueDisplayFormat = '%11.6g';
            app.yDelay.Enable = 'off';
            app.yRepeat = uieditfield(app.ParameterPanel, 'numeric');
            app.yRepeat.Limits = [1 Inf];
            app.yRepeat.ValueDisplayFormat = '%.0f';
            app.yRepeat.Enable = 'off';
            app.yRepeat.Value = 1;
            app.ZCheckBox = uicheckbox(app.ParameterPanel);
            app.ZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZCheckBoxValueChanged, true);
            app.ZCheckBox.Text = 'Z';
            app.zFrom = uieditfield(app.ParameterPanel, 'numeric');
            app.zFrom.ValueDisplayFormat = '%11.6g';
            app.zFrom.Enable = 'off';
            app.zStep = uieditfield(app.ParameterPanel, 'numeric');
            app.zStep.Limits = [0 Inf];
            app.zStep.ValueDisplayFormat = '%11.6g';
            app.zStep.Enable = 'off';
            app.zTo = uieditfield(app.ParameterPanel, 'numeric');
            app.zTo.ValueDisplayFormat = '%11.6g';
            app.zTo.Enable = 'off';
            app.zDelay = uieditfield(app.ParameterPanel, 'numeric');
            app.zDelay.Limits = [0 Inf];
            app.zDelay.ValueDisplayFormat = '%11.6g';
            app.zDelay.Enable = 'off';
            app.zRepeat = uieditfield(app.ParameterPanel, 'numeric');
            app.zRepeat.Limits = [1 Inf];
            app.zRepeat.ValueDisplayFormat = '%.0f';
            app.zRepeat.Enable = 'off';
            app.zRepeat.Value = 1;
            app.XInstr = uidropdown(app.ParameterPanel);
            app.XInstr.Items = {'-'};
            app.XInstr.ValueChangedFcn = createCallbackFcn(app, @XInstrValueChanged, true);
            app.XInstr.Value = '-';
            app.XCh = uidropdown(app.ParameterPanel);
            app.XCh.Items = {'-'};
            app.XCh.Enable = 'off';
            app.XCh.Value = '-';
            app.YInstr = uidropdown(app.ParameterPanel);
            app.YInstr.Items = {'-'};
            app.YInstr.ValueChangedFcn = createCallbackFcn(app, @YInstrValueChanged, true);
            app.YInstr.Enable = 'off';
            app.YInstr.Value = '-';
            app.YCh = uidropdown(app.ParameterPanel);
            app.YCh.Items = {'-'};
            app.YCh.Enable = 'off';
            app.YCh.Value = '-';
            app.ZInstr = uidropdown(app.ParameterPanel);
            app.ZInstr.Items = {'-'};
            app.ZInstr.ValueChangedFcn = createCallbackFcn(app, @ZInstrValueChanged, true);
            app.ZInstr.Enable = 'off';
            app.ZInstr.Value = '-';
            app.ZCh = uidropdown(app.ParameterPanel);
            app.ZCh.Items = {'-'};
            app.ZCh.Enable = 'off';
            app.ZCh.Value = '-';
            app.ChLabel = uilabel(app.ParameterPanel);
            app.ChLabel.Text = 'Ch';
            app.ReadPanel = uipanel(app.ScanTab);
            app.ChangeFilePathButton = uibutton(app.ReadPanel, 'push');
            app.ChangeFilePathButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeFilePathButtonPushed, true);
            app.ChangeFilePathButton.Text = '…';
            app.FilePathSet = uieditfield(app.ReadPanel, 'text');
            app.FilePathSet.Value = 'E:\people\HRZ\Si MOS 2018';
            app.FilePathLabel = uilabel(app.ReadPanel);
            app.FilePathLabel.Text = 'FilePath';
            app.ReadLabel = uilabel(app.ReadPanel);
            app.ReadLabel.Text = 'Read';
            app.InstrumentLabel = uilabel(app.ReadPanel);
            app.InstrumentLabel.Text = 'Instrument';
            app.ChannelLabel = uilabel(app.ReadPanel);
            app.ChannelLabel.Text = {'Channel'; ''};
            app.ReadInstrument = uidropdown(app.ReadPanel);
            app.ReadInstrument.Items = {'-'};
            app.ReadInstrument.ValueChangedFcn = createCallbackFcn(app, @ReadInstrumentValueChanged, true);
            app.ReadInstrument.Value = '-';
            app.ReadChannel = uidropdown(app.ReadPanel);
            app.ReadChannel.Items = {'-'};
            app.ReadChannel.ValueChangedFcn = createCallbackFcn(app, @ReadChannelValueChanged, true);
            app.ReadChannel.Enable = 'off';
            app.ReadChannel.Value = '-';
            app.ReadButtonGroup = uibuttongroup(app.ReadPanel);
            app.ReadButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReadButtonGroupSelectionChanged, true);
            app.ReadButtonGroup.Title = 'Ch Selected: 0';
            app.AddButton = uitogglebutton(app.ReadButtonGroup);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uitogglebutton(app.ReadButtonGroup);
            app.DeleteButton.Text = 'Delete';
            app.DeleteButton.Value = true;
            app.Button = uipanel(app.ScanTab);
            app.ScanButton = uibutton(app.Button, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.DisableLabel = uilabel(app.ScanTab);
            app.DisableLabel.Enable = 'off';
            app.DisableLabel.Visible = 'off';
            app.DisableLabel.Text = {'Scan Function is '; 'DISABLE now!'};
            app.SettingButton = uipanel(app.ScanTab);
            app.InstrumentsSettingButton = uibutton(app.SettingButton, 'push');
            app.InstrumentsSettingButton.ButtonPushedFcn = createCallbackFcn(app, @InstrumentsSettingButtonPushed, true);
            app.InstrumentsSettingButton.Text = 'Instruments Setting';
            app.InitialTab = uitab(app.TabGroup);
            app.InitialTab.Title = 'Initial';
            app.InstrDropDownLabel = uilabel(app.InitialTab);
            app.InstrDropDownLabel.Text = 'Instr';
            app.InstrDropDown = uidropdown(app.InitialTab);
            app.InstrDropDown.Items = {'-', 'lockin 1', 'lockin 2', 'itek', 'ips3'};
            app.InstrDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrDropDownValueChanged, true);
            app.InstrDropDown.Value = '-';
            app.Lockin1Panel = uipanel(app.InitialTab);
            app.Lockin1Panel.Visible = 'off';
            app.Amp1EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.Amp1EditField.ValueDisplayFormat = '%11.6g';
            app.Amp1CheckBox = uicheckbox(app.Lockin1Panel);
            app.Amp1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Amp1CheckBoxValueChanged, true);
            app.Amp1CheckBox.Text = 'Amp';
            app.Amp1CheckBox.Value = true;
            app.Freq1EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.Freq1EditField.ValueDisplayFormat = '%11.6g';
            app.Freq1CheckBox = uicheckbox(app.Lockin1Panel);
            app.Freq1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Freq1CheckBoxValueChanged, true);
            app.Freq1CheckBox.Text = 'Freq';
            app.Freq1CheckBox.Value = true;
            app.X1EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.X1EditField.ValueDisplayFormat = '%11.6g';
            app.X1EditField.Editable = 'off';
            app.X1CheckBox = uicheckbox(app.Lockin1Panel);
            app.X1CheckBox.ValueChangedFcn = createCallbackFcn(app, @X1CheckBoxValueChanged, true);
            app.X1CheckBox.Text = 'X';
            app.X1CheckBox.Value = true;
            app.Sens1CheckBox = uicheckbox(app.Lockin1Panel);
            app.Sens1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sens1CheckBoxValueChanged, true);
            app.Sens1CheckBox.Text = 'Sens';
            app.Sens1CheckBox.Value = true;
            app.Sens1DropDown = uidropdown(app.Lockin1Panel);
            app.Sens1DropDown.Items = {'2nA', '5nA', '10nA', '20nA', '50nA', '100nA', '200nA', '500nA', '1uA', '2uA', '5uA', '10uA', '20uA', '50uA', '100uA', '200uA', '500uA', '1mA', '2mA', '5mA', '10mA', '20mA', '50mA', '100mA', '200mA', '500mA', '1A'};
            app.Sens1DropDown.Value = '200mA';
            app.All1CheckBox = uicheckbox(app.Lockin1Panel);
            app.All1CheckBox.ValueChangedFcn = createCallbackFcn(app, @All1CheckBoxValueChanged, true);
            app.All1CheckBox.Text = 'All';
            app.All1CheckBox.Value = true;
            app.A1EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.A1EditField.ValueDisplayFormat = '%11.6g';
            app.A1CheckBox = uicheckbox(app.Lockin1Panel);
            app.A1CheckBox.ValueChangedFcn = createCallbackFcn(app, @A1CheckBoxValueChanged, true);
            app.A1CheckBox.Text = 'A1';
            app.A1CheckBox.Value = true;
            app.A2EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.A2EditField.ValueDisplayFormat = '%11.6g';
            app.A2CheckBox = uicheckbox(app.Lockin1Panel);
            app.A2CheckBox.ValueChangedFcn = createCallbackFcn(app, @A2CheckBoxValueChanged, true);
            app.A2CheckBox.Text = 'A2';
            app.A2CheckBox.Value = true;
            app.A3EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.A3EditField.ValueDisplayFormat = '%11.6g';
            app.A3CheckBox = uicheckbox(app.Lockin1Panel);
            app.A3CheckBox.ValueChangedFcn = createCallbackFcn(app, @A3CheckBoxValueChanged, true);
            app.A3CheckBox.Text = 'A3';
            app.A3CheckBox.Value = true;
            app.A4EditField = uieditfield(app.Lockin1Panel, 'numeric');
            app.A4EditField.ValueDisplayFormat = '%11.6g';
            app.A4CheckBox = uicheckbox(app.Lockin1Panel);
            app.A4CheckBox.ValueChangedFcn = createCallbackFcn(app, @A4CheckBoxValueChanged, true);
            app.A4CheckBox.Text = 'A4';
            app.A4CheckBox.Value = true;
            app.Lockin2Panel = uipanel(app.InitialTab);
            app.Lockin2Panel.Visible = 'off';
            app.B1EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.B1EditField.ValueDisplayFormat = '%11.6g';
            app.B1CheckBox = uicheckbox(app.Lockin2Panel);
            app.B1CheckBox.ValueChangedFcn = createCallbackFcn(app, @B1CheckBoxValueChanged, true);
            app.B1CheckBox.Text = 'B1';
            app.B1CheckBox.Value = true;
            app.B2EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.B2EditField.ValueDisplayFormat = '%11.6g';
            app.B2CheckBox = uicheckbox(app.Lockin2Panel);
            app.B2CheckBox.ValueChangedFcn = createCallbackFcn(app, @B2CheckBoxValueChanged, true);
            app.B2CheckBox.Text = 'B2';
            app.B2CheckBox.Value = true;
            app.B3EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.B3EditField.ValueDisplayFormat = '%11.6g';
            app.B3CheckBox = uicheckbox(app.Lockin2Panel);
            app.B3CheckBox.ValueChangedFcn = createCallbackFcn(app, @B3CheckBoxValueChanged, true);
            app.B3CheckBox.Text = 'B3';
            app.B3CheckBox.Value = true;
            app.B4EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.B4EditField.ValueDisplayFormat = '%11.6g';
            app.B4CheckBox = uicheckbox(app.Lockin2Panel);
            app.B4CheckBox.ValueChangedFcn = createCallbackFcn(app, @B4CheckBoxValueChanged, true);
            app.B4CheckBox.Text = 'B4';
            app.B4CheckBox.Value = true;
            app.Amp2EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.Amp2EditField.ValueDisplayFormat = '%11.6g';
            app.Amp2CheckBox = uicheckbox(app.Lockin2Panel);
            app.Amp2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Amp2CheckBoxValueChanged, true);
            app.Amp2CheckBox.Text = 'Amp';
            app.Amp2CheckBox.Value = true;
            app.Freq2EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.Freq2EditField.ValueDisplayFormat = '%11.6g';
            app.Freq2CheckBox = uicheckbox(app.Lockin2Panel);
            app.Freq2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Freq2CheckBoxValueChanged, true);
            app.Freq2CheckBox.Text = 'Freq';
            app.Freq2CheckBox.Value = true;
            app.X2EditField = uieditfield(app.Lockin2Panel, 'numeric');
            app.X2EditField.ValueDisplayFormat = '%11.6g';
            app.X2CheckBox = uicheckbox(app.Lockin2Panel);
            app.X2CheckBox.ValueChangedFcn = createCallbackFcn(app, @X2CheckBoxValueChanged, true);
            app.X2CheckBox.Text = 'X';
            app.X2CheckBox.Value = true;
            app.Sens2CheckBox = uicheckbox(app.Lockin2Panel);
            app.Sens2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Sens2CheckBoxValueChanged, true);
            app.Sens2CheckBox.Text = 'Sens';
            app.Sens2CheckBox.Value = true;
            app.Sens2DropDown = uidropdown(app.Lockin2Panel);
            app.Sens2DropDown.Items = {'2nA', '5nA', '10nA', '20nA', '50nA', '100nA', '200nA', '500nA', '1uA', '2uA', '5uA', '10uA', '20uA', '50uA', '100uA', '200uA', '500uA', '1mA', '2mA', '5mA', '10mA', '20mA', '50mA', '100mA', '200mA', '500mA', '1A'};
            app.Sens2DropDown.Value = '2nA';
            app.All2CheckBox = uicheckbox(app.Lockin2Panel);
            app.All2CheckBox.ValueChangedFcn = createCallbackFcn(app, @All2CheckBoxValueChanged, true);
            app.All2CheckBox.Text = 'All';
            app.All2CheckBox.Value = true;
            app.ItekPanel = uipanel(app.InitialTab);
            app.ItekPanel.Visible = 'off';
            app.U1EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U1EditField.ValueDisplayFormat = '%11.6g';
            app.U1CheckBox = uicheckbox(app.ItekPanel);
            app.U1CheckBox.ValueChangedFcn = createCallbackFcn(app, @U1CheckBoxValueChanged, true);
            app.U1CheckBox.Text = 'U1';
            app.U1CheckBox.Value = true;
            app.All3CheckBox = uicheckbox(app.ItekPanel);
            app.All3CheckBox.ValueChangedFcn = createCallbackFcn(app, @All3CheckBoxValueChanged, true);
            app.All3CheckBox.Text = 'All';
            app.All3CheckBox.Value = true;
            app.U2EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U2EditField.ValueDisplayFormat = '%11.6g';
            app.U2CheckBox = uicheckbox(app.ItekPanel);
            app.U2CheckBox.ValueChangedFcn = createCallbackFcn(app, @U2CheckBoxValueChanged, true);
            app.U2CheckBox.Text = 'U2';
            app.U2CheckBox.Value = true;
            app.U3EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U3EditField.ValueDisplayFormat = '%11.6g';
            app.U3CheckBox = uicheckbox(app.ItekPanel);
            app.U3CheckBox.ValueChangedFcn = createCallbackFcn(app, @U3CheckBoxValueChanged, true);
            app.U3CheckBox.Text = 'U3';
            app.U3CheckBox.Value = true;
            app.U4EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U4EditField.ValueDisplayFormat = '%11.6g';
            app.U4CheckBox = uicheckbox(app.ItekPanel);
            app.U4CheckBox.ValueChangedFcn = createCallbackFcn(app, @U4CheckBoxValueChanged, true);
            app.U4CheckBox.Text = 'U4';
            app.U4CheckBox.Value = true;
            app.U5EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U5EditField.ValueDisplayFormat = '%11.6g';
            app.U5CheckBox = uicheckbox(app.ItekPanel);
            app.U5CheckBox.ValueChangedFcn = createCallbackFcn(app, @U5CheckBoxValueChanged, true);
            app.U5CheckBox.Text = 'U5';
            app.U5CheckBox.Value = true;
            app.U6EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U6EditField.ValueDisplayFormat = '%11.6g';
            app.U6CheckBox = uicheckbox(app.ItekPanel);
            app.U6CheckBox.ValueChangedFcn = createCallbackFcn(app, @U6CheckBoxValueChanged, true);
            app.U6CheckBox.Text = 'U6';
            app.U6CheckBox.Value = true;
            app.U7EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U7EditField.ValueDisplayFormat = '%11.6g';
            app.U7CheckBox = uicheckbox(app.ItekPanel);
            app.U7CheckBox.ValueChangedFcn = createCallbackFcn(app, @U7CheckBoxValueChanged, true);
            app.U7CheckBox.Text = 'U7';
            app.U7CheckBox.Value = true;
            app.U8EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U8EditField.ValueDisplayFormat = '%11.6g';
            app.U8CheckBox = uicheckbox(app.ItekPanel);
            app.U8CheckBox.ValueChangedFcn = createCallbackFcn(app, @U8CheckBoxValueChanged, true);
            app.U8CheckBox.Text = 'U8';
            app.U8CheckBox.Value = true;
            app.U9EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U9EditField.ValueDisplayFormat = '%11.6g';
            app.U9CheckBox = uicheckbox(app.ItekPanel);
            app.U9CheckBox.ValueChangedFcn = createCallbackFcn(app, @U9CheckBoxValueChanged, true);
            app.U9CheckBox.Text = 'U9';
            app.U9CheckBox.Value = true;
            app.U10EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U10EditField.ValueDisplayFormat = '%11.6g';
            app.U10CheckBox = uicheckbox(app.ItekPanel);
            app.U10CheckBox.ValueChangedFcn = createCallbackFcn(app, @U10CheckBoxValueChanged, true);
            app.U10CheckBox.Text = 'U10';
            app.U10CheckBox.Value = true;
            app.U11EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U11EditField.ValueDisplayFormat = '%11.6g';
            app.U11CheckBox = uicheckbox(app.ItekPanel);
            app.U11CheckBox.ValueChangedFcn = createCallbackFcn(app, @U11CheckBoxValueChanged, true);
            app.U11CheckBox.Text = 'U11';
            app.U11CheckBox.Value = true;
            app.U12EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U12EditField.ValueDisplayFormat = '%11.6g';
            app.U12CheckBox = uicheckbox(app.ItekPanel);
            app.U12CheckBox.ValueChangedFcn = createCallbackFcn(app, @U12CheckBoxValueChanged, true);
            app.U12CheckBox.Text = 'U12';
            app.U12CheckBox.Value = true;
            app.U13EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U13EditField.ValueDisplayFormat = '%11.6g';
            app.U13CheckBox = uicheckbox(app.ItekPanel);
            app.U13CheckBox.ValueChangedFcn = createCallbackFcn(app, @U13CheckBoxValueChanged, true);
            app.U13CheckBox.Text = 'U13';
            app.U13CheckBox.Value = true;
            app.U14EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U14EditField.ValueDisplayFormat = '%11.6g';
            app.U14CheckBox = uicheckbox(app.ItekPanel);
            app.U14CheckBox.ValueChangedFcn = createCallbackFcn(app, @U14CheckBoxValueChanged, true);
            app.U14CheckBox.Text = 'U14';
            app.U14CheckBox.Value = true;
            app.U15EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U15EditField.ValueDisplayFormat = '%11.6g';
            app.U15CheckBox = uicheckbox(app.ItekPanel);
            app.U15CheckBox.ValueChangedFcn = createCallbackFcn(app, @U15CheckBoxValueChanged, true);
            app.U15CheckBox.Text = 'U15';
            app.U15CheckBox.Value = true;
            app.U16EditField = uieditfield(app.ItekPanel, 'numeric');
            app.U16EditField.ValueDisplayFormat = '%11.6g';
            app.U16CheckBox = uicheckbox(app.ItekPanel);
            app.U16CheckBox.ValueChangedFcn = createCallbackFcn(app, @U16CheckBoxValueChanged, true);
            app.U16CheckBox.Text = 'U16';
            app.U16CheckBox.Value = true;
            app.Ips3Panel = uipanel(app.InitialTab);
            app.Ips3Panel.Visible = 'off';
            app.XtEditField = uieditfield(app.Ips3Panel, 'numeric');
            app.XtEditField.ValueDisplayFormat = '%11.6g';
            app.XtCheckBox = uicheckbox(app.Ips3Panel);
            app.XtCheckBox.ValueChangedFcn = createCallbackFcn(app, @XtCheckBoxValueChanged, true);
            app.XtCheckBox.Text = 'X/t';
            app.XtCheckBox.Value = true;
            app.ZtEditField = uieditfield(app.Ips3Panel, 'numeric');
            app.ZtEditField.ValueDisplayFormat = '%11.6g';
            app.ZtCheckBox = uicheckbox(app.Ips3Panel);
            app.ZtCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZtCheckBoxValueChanged, true);
            app.ZtCheckBox.Text = 'Z/t';
            app.ZtCheckBox.Value = true;
            app.XfEditField = uieditfield(app.Ips3Panel, 'numeric');
            app.XfEditField.ValueDisplayFormat = '%11.6g';
            app.XfCheckBox = uicheckbox(app.Ips3Panel);
            app.XfCheckBox.ValueChangedFcn = createCallbackFcn(app, @XfCheckBoxValueChanged, true);
            app.XfCheckBox.Text = 'X';
            app.XfCheckBox.Value = true;
            app.HeatCheckBox = uicheckbox(app.Ips3Panel);
            app.HeatCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeatCheckBoxValueChanged, true);
            app.HeatCheckBox.Text = 'Heat';
            app.HeatCheckBox.Value = true;
            app.HoldCheckBox = uicheckbox(app.Ips3Panel);
            app.HoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldCheckBoxValueChanged, true);
            app.HoldCheckBox.Text = 'Hold';
            app.HoldCheckBox.Value = true;
            app.ToZeroCheckBox = uicheckbox(app.Ips3Panel);
            app.ToZeroCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToZeroCheckBoxValueChanged, true);
            app.ToZeroCheckBox.Text = 'To 0';
            app.ToZeroCheckBox.Value = true;
            app.All4CheckBox = uicheckbox(app.Ips3Panel);
            app.All4CheckBox.ValueChangedFcn = createCallbackFcn(app, @All4CheckBoxValueChanged, true);
            app.All4CheckBox.Text = 'All';
            app.All4CheckBox.Value = true;
            app.ToZeroButton = uibutton(app.Ips3Panel, 'state');
            app.ToZeroButton.ValueChangedFcn = createCallbackFcn(app, @ToZeroButtonValueChanged, true);
            app.ToZeroButton.Text = 'To Zero';
            app.HoldButton = uibutton(app.Ips3Panel, 'state');
            app.HoldButton.ValueChangedFcn = createCallbackFcn(app, @HoldButtonValueChanged, true);
            app.HoldButton.Text = '?';
            app.HeatButton = uibutton(app.Ips3Panel, 'state');
            app.HeatButton.ValueChangedFcn = createCallbackFcn(app, @HeatButtonValueChanged, true);
            app.HeatButton.Text = '?';
            app.ZfPanel = uipanel(app.Ips3Panel);
            app.ZinEditField = uieditfield(app.ZfPanel, 'numeric');
            app.ZinEditField.ValueDisplayFormat = '%11.6g';
            app.ZinEditField.Editable = 'off';
            app.ZinLabel = uilabel(app.ZfPanel);
            app.ZinLabel.Text = 'Z in';
            app.ZexLabel = uilabel(app.ZfPanel);
            app.ZexLabel.Text = 'Z ex';
            app.ZexEditField = uieditfield(app.ZfPanel, 'numeric');
            app.ZexEditField.ValueDisplayFormat = '%11.6g';
            app.ZfCheckBox = uicheckbox(app.ZfPanel);
            app.ZfCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZfCheckBoxValueChanged, true);
            app.ZfCheckBox.Text = '';
            app.ZfCheckBox.Value = true;
            app.A33522aPanel = uipanel(app.InitialTab);
            app.A33522aPanel.Title = 'A33522a';
            app.A33522aPanel.Visible = 'off';
            app.Channel2Panel = uipanel(app.A33522aPanel);
            app.Channel2Panel.Title = 'Channel 2';
            app.Ch2CheckBox = uicheckbox(app.Channel2Panel);
            app.Ch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch2CheckBoxValueChanged, true);
            app.Ch2CheckBox.Text = 'Ch2';
            app.Ch2CheckBox.Value = true;
            app.Ch2PrdEditField = uieditfield(app.Channel2Panel, 'numeric');
            app.Ch2PrdEditField.ValueDisplayFormat = '%11.6g';
            app.Ch2PrdCheckBox = uicheckbox(app.Channel2Panel);
            app.Ch2PrdCheckBox.Text = 'Prd';
            app.Ch2PrdCheckBox.Value = true;
            app.Ch2AmpEditField = uieditfield(app.Channel2Panel, 'numeric');
            app.Ch2AmpEditField.ValueDisplayFormat = '%11.6g';
            app.Ch2AmpCheckBox = uicheckbox(app.Channel2Panel);
            app.Ch2AmpCheckBox.Text = 'Amp';
            app.Ch2AmpCheckBox.Value = true;
            app.Ch2FreqEditField = uieditfield(app.Channel2Panel, 'numeric');
            app.Ch2FreqEditField.ValueDisplayFormat = '%11.6g';
            app.Ch2FreqCheckBox = uicheckbox(app.Channel2Panel);
            app.Ch2FreqCheckBox.Text = 'Freq';
            app.Ch2FreqCheckBox.Value = true;
            app.Channel1Panel = uipanel(app.A33522aPanel);
            app.Channel1Panel.Title = 'Channel 1';
            app.Ch1PrdEditField = uieditfield(app.Channel1Panel, 'numeric');
            app.Ch1PrdEditField.ValueDisplayFormat = '%11.6g';
            app.Ch1PrdCheckBox = uicheckbox(app.Channel1Panel);
            app.Ch1PrdCheckBox.Text = 'Prd';
            app.Ch1PrdCheckBox.Value = true;
            app.Ch1AmpEditField = uieditfield(app.Channel1Panel, 'numeric');
            app.Ch1AmpEditField.ValueDisplayFormat = '%11.6g';
            app.Ch1AmpCheckBox = uicheckbox(app.Channel1Panel);
            app.Ch1AmpCheckBox.Text = 'Amp';
            app.Ch1AmpCheckBox.Value = true;
            app.Ch1FreqEditField = uieditfield(app.Channel1Panel, 'numeric');
            app.Ch1FreqEditField.ValueDisplayFormat = '%11.6g';
            app.Ch1FreqCheckBox = uicheckbox(app.Channel1Panel);
            app.Ch1FreqCheckBox.Text = 'Freq';
            app.Ch1FreqCheckBox.Value = true;
            app.Ch1CheckBox = uicheckbox(app.Channel1Panel);
            app.Ch1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch1CheckBoxValueChanged, true);
            app.Ch1CheckBox.Text = 'Ch1';
            app.Ch1CheckBox.Value = true;
            app.initPanel = uipanel(app.InitialTab);
            app.ReadButton = uibutton(app.initPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.InitButton = uibutton(app.initPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.StopButton = uibutton(app.initPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ScanInit_EA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end